classdef stimulus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WestAttenuatorSlider           matlab.ui.control.Slider
        WestAttenuatorSliderLabel      matlab.ui.control.Label
        EastAttenuatorSlider           matlab.ui.control.Slider
        EastAttenuatorSliderLabel      matlab.ui.control.Label
        CombineOutputCheckBox          matlab.ui.control.CheckBox
        SpeciesDropDown                matlab.ui.control.DropDown
        SpeciesDropDownLabel           matlab.ui.control.Label
        Inter_speaker_interval         matlab.ui.control.Spinner
        InterSpeakerIntervalmsecLabel  matlab.ui.control.Label
        ISI                            matlab.ui.control.Spinner
        InterStimulusIntervalsecLabel  matlab.ui.control.Label
        EastSpeakerPanel               matlab.ui.container.Panel
        East_current_modulator         matlab.ui.control.NumericEditField
        East_current_carrier           matlab.ui.control.NumericEditField
        East_plot_type                 matlab.ui.control.RockerSwitch
        East_stimulus_plot             matlab.ui.control.UIAxes
        East_carrier_plot              matlab.ui.control.UIAxes
        East_modulator_plot            matlab.ui.control.UIAxes
        WestSpeakerPanel               matlab.ui.container.Panel
        West_current_modulator         matlab.ui.control.NumericEditField
        West_current_carrier           matlab.ui.control.NumericEditField
        West_plot_type                 matlab.ui.control.RockerSwitch
        West_stimulus_plot             matlab.ui.control.UIAxes
        West_carrier_plot              matlab.ui.control.UIAxes
        West_modulator_plot            matlab.ui.control.UIAxes
        StatusPanel                    matlab.ui.container.Panel
        LoadParametersLamp             matlab.ui.control.Lamp
        LoadParametersLampLabel        matlab.ui.control.Label
        LoadCircuitLamp                matlab.ui.control.Lamp
        LoadCircuitLampLabel           matlab.ui.control.Label
        ConnectionLamp                 matlab.ui.control.Lamp
        ConnectionLampLabel            matlab.ui.control.Label
        ControlSwitch                  matlab.ui.control.ToggleSwitch
        Speaker                        matlab.ui.control.DiscreteKnob
        TabGroup                       matlab.ui.container.TabGroup
        SearchTab                      matlab.ui.container.Tab
        Search_stimulus_duration       matlab.ui.control.Spinner
        StimulusDurationmsecLabel      matlab.ui.control.Label
        ControlPanel                   matlab.ui.container.Panel
        FrequencyCyclerHzPanel         matlab.ui.container.Panel
        End_frequency                  matlab.ui.control.NumericEditField
        EndEditFieldLabel              matlab.ui.control.Label
        Step_frequency                 matlab.ui.control.NumericEditField
        StepEditFieldLabel             matlab.ui.control.Label
        Start_frequency                matlab.ui.control.NumericEditField
        StartEditFieldLabel            matlab.ui.control.Label
        ModeDropDown                   matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        ModulatorFrequenciesHzPanel    matlab.ui.container.Panel
        Fm_check_5                     matlab.ui.control.CheckBox
        Fm_5                           matlab.ui.control.NumericEditField
        Fm_check_4                     matlab.ui.control.CheckBox
        Fm_4                           matlab.ui.control.NumericEditField
        Fm_check_3                     matlab.ui.control.CheckBox
        Fm_3                           matlab.ui.control.NumericEditField
        Fm_check_2                     matlab.ui.control.CheckBox
        Fm_2                           matlab.ui.control.NumericEditField
        Fm_1                           matlab.ui.control.NumericEditField
        Fm_check_1                     matlab.ui.control.CheckBox
        CarrierFrequenciesHzPanel      matlab.ui.container.Panel
        Fc_check_5                     matlab.ui.control.CheckBox
        Fc_5                           matlab.ui.control.NumericEditField
        Fc_check_4                     matlab.ui.control.CheckBox
        Fc_4                           matlab.ui.control.NumericEditField
        Fc_check_3                     matlab.ui.control.CheckBox
        Fc_3                           matlab.ui.control.NumericEditField
        Fc_check_2                     matlab.ui.control.CheckBox
        Fc_2                           matlab.ui.control.NumericEditField
        Fc_1                           matlab.ui.control.NumericEditField
        Fc_check_1                     matlab.ui.control.CheckBox
        PRRTab                         matlab.ui.container.Tab
        WestPanel                      matlab.ui.container.Panel
        West_PRR_Carrier               matlab.ui.control.NumericEditField
        CarrierHzEditField_2Label      matlab.ui.control.Label
        West_PRR_multiGap_check        matlab.ui.control.CheckBox
        WestMasker                     matlab.ui.control.ListBox
        MaskerListBox_2Label           matlab.ui.control.Label
        West_PRR_nPulses_check         matlab.ui.control.CheckBox
        West_PRR_Gap_time              matlab.ui.control.NumericEditField
        GaptimemsecLabel_2             matlab.ui.control.Label
        West_PRR_Gap_after_pulse       matlab.ui.control.NumericEditField
        GapafterpulseLabel_2           matlab.ui.control.Label
        West_PRR_Pulse_rate            matlab.ui.control.NumericEditField
        PulserateppsLabel_2            matlab.ui.control.Label
        West_PRR_RT                    matlab.ui.control.NumericEditField
        RTLabel_2                      matlab.ui.control.Label
        West_PRR_RT_units              matlab.ui.control.DropDown
        West_PRR_PD_units              matlab.ui.control.DropDown
        West_PRR_PD                    matlab.ui.control.NumericEditField
        PDLabel_2                      matlab.ui.control.Label
        West_PRR_nPulses               matlab.ui.control.NumericEditField
        nPulsesLabel_2                 matlab.ui.control.Label
        EastPanel                      matlab.ui.container.Panel
        East_PRR_Carrier               matlab.ui.control.NumericEditField
        CarrierHzEditFieldLabel        matlab.ui.control.Label
        East_PRR_multiGap_check        matlab.ui.control.CheckBox
        EastMasker                     matlab.ui.control.ListBox
        MaskerListBoxLabel             matlab.ui.control.Label
        East_PRR_nPulses_check         matlab.ui.control.CheckBox
        East_PRR_Gap_time              matlab.ui.control.NumericEditField
        GaptimemsecLabel               matlab.ui.control.Label
        East_PRR_Gap_after_pulse       matlab.ui.control.NumericEditField
        GapafterpulseLabel             matlab.ui.control.Label
        East_PRR_Pulse_rate            matlab.ui.control.NumericEditField
        PulserateppsLabel              matlab.ui.control.Label
        East_PRR_RT                    matlab.ui.control.NumericEditField
        RTLabel                        matlab.ui.control.Label
        East_PRR_RT_units              matlab.ui.control.DropDown
        East_PRR_PD_units              matlab.ui.control.DropDown
        East_PRR_PD                    matlab.ui.control.NumericEditField
        PDLabel                        matlab.ui.control.Label
        East_PRR_nPulses               matlab.ui.control.NumericEditField
        nPulsesLabel                   matlab.ui.control.Label
        ControlPanel_PRR               matlab.ui.container.Panel
        NaturalPulseDropDown           matlab.ui.control.DropDown
        NaturalPulseDropDownLabel      matlab.ui.control.Label
        PRREnvelope                    matlab.ui.control.DropDown
        EnvelopeDropDownLabel          matlab.ui.control.Label
        PRRCyclerppsCyclePanel         matlab.ui.container.Panel
        Fm_PRR_end                     matlab.ui.control.NumericEditField
        EndEditFieldLabel_2            matlab.ui.control.Label
        Fm_PRR_step                    matlab.ui.control.NumericEditField
        StepEditFieldLabel_2           matlab.ui.control.Label
        Fm_PRR_start                   matlab.ui.control.NumericEditField
        StartEditFieldLabel_2          matlab.ui.control.Label
        PRR_mode                       matlab.ui.control.DropDown
        PRRModeDropDownLabel           matlab.ui.control.Label
        PRRppsSelPanel                 matlab.ui.container.Panel
        Fm_PRR_check_5                 matlab.ui.control.CheckBox
        Fm_PRR_5                       matlab.ui.control.NumericEditField
        Fm_PRR_check_4                 matlab.ui.control.CheckBox
        Fm_PRR_4                       matlab.ui.control.NumericEditField
        Fm_PRR_check_3                 matlab.ui.control.CheckBox
        Fm_PRR_3                       matlab.ui.control.NumericEditField
        Fm_PRR_check_2                 matlab.ui.control.CheckBox
        Fm_PRR_2                       matlab.ui.control.NumericEditField
        Fm_PRR_1                       matlab.ui.control.NumericEditField
        Fm_PRR_check_1                 matlab.ui.control.CheckBox
        EnergyPanel                    matlab.ui.container.Panel
        Ec_PRR_5                       matlab.ui.control.NumericEditField
        Ec_PRR_4                       matlab.ui.control.NumericEditField
        Ec_PRR_3                       matlab.ui.control.NumericEditField
        Ec_PRR_2                       matlab.ui.control.NumericEditField
        Ec_PRR_1                       matlab.ui.control.NumericEditField
        CarrierFrequenciesHzPanel_2    matlab.ui.container.Panel
        Fc_PRR_check_5                 matlab.ui.control.CheckBox
        Fc_PRR_5                       matlab.ui.control.NumericEditField
        Fc_PRR_check_4                 matlab.ui.control.CheckBox
        Fc_PRR_4                       matlab.ui.control.NumericEditField
        Fc_PRR_check_3                 matlab.ui.control.CheckBox
        Fc_PRR_3                       matlab.ui.control.NumericEditField
        Fc_PRR_check_2                 matlab.ui.control.CheckBox
        Fc_PRR_2                       matlab.ui.control.NumericEditField
        Fc_PRR_1                       matlab.ui.control.NumericEditField
        Fc_PRR_check_1                 matlab.ui.control.CheckBox
        NaturalTab                     matlab.ui.container.Tab
        ControlPanel_Natural           matlab.ui.container.Panel
        NaturalToneHz                  matlab.ui.control.NumericEditField
        ToneHzEditFieldLabel           matlab.ui.control.Label
        NaturalCarrier                 matlab.ui.control.DropDown
        CarrierDropDownLabel           matlab.ui.control.Label
        NaturalCall                    matlab.ui.control.DropDown
        CallDropDownLabel              matlab.ui.control.Label
        NaturalPlayMode                matlab.ui.control.DropDown
        PlayModeLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        RPcontroller % actxcontrol of RP2.1 processor.s
        sampling_rate = 24144.0625; % Hz. Sampling rate of RP2.1 processor.
        debug = false;
        species_list;
        calls_list;
        calls;
        call_envelopes;
        call_pulses_list;
        call_pulses;
        masker;
        reference_amplitude;
    end
    
    methods (Access = private)

        function [n, signal_fft] = compute_fft(~, signal, time)
            samples = size(time, 2);
            n = 2^nextpow2(samples);
            signal_fft = fft(signal, n, 2);
            signal_fft = abs(signal_fft/samples);
            signal_fft = signal_fft(:, 1:n/2+1);
            signal_fft(:, 2:end-1) = 2*signal_fft(:, 2:end-1);
        end
        
        %% Initialization
        function hardware_initialization(app)
            app.RPcontroller = actxserver('RPco.x');
            if app.RPcontroller.ConnectRP2('GB', 1)
                app.ConnectionLamp.Color = [0, 1, 0];
            else
                app.ConnectionLamp.Color = [1, 0, 0];
            end
        end
        
        function natural_calls_initialization(app)
            % Get species_list from sub-folders of ./calls folder
            d = dir("./calls");
            folders = {d([d(:).isdir]).name};
            folders = folders(~startsWith(folders, '.'));  % removes ., .., .git, .whatever
            app.species_list = string(folders);

            % Initialize calls for each species
            for i_species = 1:numel(app.species_list)
                species = app.species_list(i_species);
                d_species = dir(strcat("./calls/", species));
                listing = {d_species(~[d_species(:).isdir]).name};
                listing = listing(~startsWith(listing, '.'));  % removes ., .., .git, .whatever
                n_calls = length(listing);
                app.calls_list.(species) = strings(n_calls, 1);
                app.calls.(species) = cell(n_calls, 1);
                app.call_envelopes.(species) = cell(n_calls, 1);
                for i_call = 1:n_calls
                    call_name = char(listing(i_call));
                    app.calls_list.(species){i_call, 1} = call_name;
                    [call, ~] = audioread(strcat("./calls/", species, "/", call_name));
                    call = app.audio_norm(call');
                    app.calls.(species){i_call, 1} = call;
                    app.call_envelopes.(species){i_call, 1} = app.compute_signal_envelope(call);
                end
            end

        end

        function e = load_circuit(app, circuit)
            e = app.RPcontroller.LoadCOF(circuit);
            if e
                app.LoadCircuitLamp.Color = [0, 1, 0];
            else
                app.LoadCircuitLamp.Color = [1, 0, 0];
            end
        end


        %% General Utilities 
        % Signal Processing
        function carrier = build_tonal_carrier(~, time, carrier_cfg)
            carrier = zeros(size(time));
            for i = 1: 1: length(carrier_cfg.fcs)
                carrier = carrier + carrier_cfg.A(i)*sin(2*pi*carrier_cfg.fcs(i).*time);
            end
        end

        function envelope = compute_signal_envelope(~, signal)
            envelope = abs(hilbert(signal));
        end

        function amp = attenuate(app, dB)
            amp = app.reference_amplitude*10^(dB/20);
        end

        function [normed] = audio_norm(~, sig)
            normed = sig - median(sig);
            normed = normed ./ ((max(normed) - min(normed)) / 2);
        end


        %% Search Tab Functions
        function search_mode_normal(app)
            % Search Tab normal mode configuration.
            app.Fc_check_1.Value = 1;
            app.Fc_check_2.Value = 1;
            app.Fc_check_3.Value = 1;
            app.Fc_check_4.Value = 1;
            app.Fc_check_5.Value = 1;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 1;
            app.Fm_check_2.Value = 1;
            app.Fm_check_3.Value = 1;
            app.Fm_check_4.Value = 1;
            app.Fm_check_5.Value = 1;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 0;
            app.Step_frequency.Enable = 0;
            app.End_frequency.Enable = 0;
        end
        
        function search_mode_fixed_carrier(app)
            % Fixed carrier configuration.
            app.Fc_check_1.Value = 0;
            app.Fc_check_2.Value = 0;
            app.Fc_check_3.Value = 0;
            app.Fc_check_4.Value = 0;
            app.Fc_check_5.Value = 1;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 0;
            app.Fm_check_2.Value = 0;
            app.Fm_check_3.Value = 0;
            app.Fm_check_4.Value = 0;
            app.Fm_check_5.Value = 0;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 1;
            app.Start_frequency.Value = 10;
            app.Step_frequency.Enable = 1;
            app.Step_frequency.Value = 10;
            app.End_frequency.Enable = 1;
            app.End_frequency.Value = 80;
        end
        
        function search_mode_fixed_modulation(app)
            % Fixed modulation configuration.
            app.Fc_check_1.Value = 0;
            app.Fc_check_2.Value = 0;
            app.Fc_check_3.Value = 0;
            app.Fc_check_4.Value = 0;
            app.Fc_check_5.Value = 0;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 0;
            app.Fm_check_2.Value = 0;
            app.Fm_check_3.Value = 0;
            app.Fm_check_4.Value = 0;
            app.Fm_check_5.Value = 1;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 1;
            app.Start_frequency.Value = 300;
            app.Step_frequency.Enable = 1;
            app.Step_frequency.Value = 20;
            app.End_frequency.Enable = 1;
            app.End_frequency.Value = 600;
        end
        
        function freqs = search_get_carriers(app)
            % Collect active carrier frequencies.
            mode = app.ModeDropDown.Value;
            if mode == "Fixed Modulation"
                freqs = app.Start_frequency.Value: app.Step_frequency.Value: app.End_frequency.Value;
            else
                freqs = [];
                if app.Fc_check_1.Value == 1
                    freqs(end+1) = app.Fc_1.Value;
                end
                if app.Fc_check_2.Value == 1
                    freqs(end+1) = app.Fc_2.Value;
                end
                if app.Fc_check_3.Value == 1
                    freqs(end+1) = app.Fc_3.Value;
                end
                if app.Fc_check_4.Value == 1
                    freqs(end+1) = app.Fc_4.Value;
                end
                if app.Fc_check_5.Value == 1
                    freqs(end+1) = app.Fc_5.Value;
                end
            end
        end
        
        function freqs = search_get_modulators(app)
            % Collect modulation frequencies.
            mode = app.ModeDropDown.Value;
            if mode == "Fixed Carrier"
                freqs = app.Start_frequency.Value: app.Step_frequency.Value: app.End_frequency.Value;
            else
                freqs = [];
                if app.Fm_check_1.Value == 1
                    freqs(end+1) = app.Fm_1.Value;
                end
                if app.Fm_check_2.Value == 1
                    freqs(end+1) = app.Fm_2.Value;
                end
                if app.Fm_check_3.Value == 1
                    freqs(end+1) = app.Fm_3.Value;
                end
                if app.Fm_check_4.Value == 1
                    freqs(end+1) = app.Fm_4.Value;
                end
                if app.Fm_check_5.Value == 1
                    freqs(end+1) = app.Fm_5.Value;
                end
            end
        end
        
        function [time, carrier, modulator, stimulus, stimulus_trigger, pulse_trigger] = build_AM(app, carrier_frequency, modulator_frequency, append_time, region)
            % Generate Search Stimulus.
            stimulus_duration = app.Search_stimulus_duration.Value*1e-3; % Inter-stimulus interval. Converted from msec to sec.
            append_samples = floor(app.sampling_rate*append_time);
            time = 0: (1/app.sampling_rate) : (stimulus_duration - (1/app.sampling_rate)); % Array of time samples.
            stimulus_trigger = zeros(size(time));
            stimulus_trigger(1:end, 1) = 1;
            pulse_trigger = zeros(size(time));
            pulse_trigger(1:end, 1) = 1;
            if region == "start"
                carrier = cat(2, zeros(1, append_samples), sin(2*pi*carrier_frequency.*time)); % Generate carrier sinusoid for all carrier frequencies.
                modulator = cat(2, zeros(1, append_samples), sin(2*pi*modulator_frequency.*time - pi/2)); % Generate modulator sinusoid for all modulator frequencies.
                stimulus_trigger = cat(2, zeros(1, append_samples), stimulus_trigger);
                pulse_trigger = cat(2, zeros(1, append_samples), pulse_trigger);
            elseif region == "end"
                carrier = cat(2, sin(2*pi*carrier_frequency.*time), zeros(1, append_samples));
                modulator = cat(2, sin(2*pi*modulator_frequency.*time - pi/2), zeros(1, append_samples)); % The correct modulator is sin(2*pi*fm*t + pi/2) + 1; 1 is added later in the code.
                stimulus_trigger = cat(2, stimulus_trigger, zeros(1, append_samples));
                pulse_trigger = cat(2, pulse_trigger, zeros(1, append_samples));
            end
            modulator = 0.5*modulator + 0.5; % adding 1
            stimulus = carrier .* modulator; % Generate stimuli.
            time = 0: (1/app.sampling_rate): ((size(stimulus, 2)-1)/app.sampling_rate);
        end
        
        function [time, carrier, modulator, stimulus, stimulus_trigger, pulse_trigger] = build_biaural_search_stimulus(app, carrier_frequency, modulator_frequency)
            speaker_interval = app.Inter_speaker_interval.Value*1e-3;
            if speaker_interval >= 0
                [West.time, West.carrier, West.modulator, West.stimulus, West.stimulus_trigger, West.pulse_trigger] = app.build_AM(carrier_frequency, modulator_frequency, abs(speaker_interval), "end");
                [East.time, East.carrier, East.modulator, East.stimulus, East.stimulus_trigger, East.pulse_trigger] = app.build_AM(carrier_frequency, modulator_frequency, abs(speaker_interval), "start");
            elseif speaker_interval < 0
                [West.time, West.carrier, West.modulator, West.stimulus, West.stimulus_trigger, West.pulse_trigger] = app.build_AM(carrier_frequency, modulator_frequency, abs(speaker_interval), "start");
                [East.time, East.carrier, East.modulator, East.stimulus, East.stimulus_trigger, East.pulse_trigger] = app.build_AM(carrier_frequency, modulator_frequency, abs(speaker_interval), "end");
            end
            if app.Speaker.Value == "East"
                app.East_current_carrier.Value = carrier_frequency;
                app.East_current_modulator.Value = modulator_frequency;
                app.West_current_carrier.Value = 0;
                app.West_current_modulator.Value = 0;
                West.carrier = West.carrier .* 0;
                West.modulator = West.modulator .* 0;
                West.stimulus = West.stimulus .* 0;
                West.stimulus_trigger = West.stimulus_trigger .* 0;
                West.pulse_trigger = West.pulse_trigger .* 0;
            elseif app.Speaker.Value == "West"
                app.East_current_carrier.Value = 0;
                app.East_current_modulator.Value = 0;
                app.West_current_carrier.Value = carrier_frequency;
                app.West_current_modulator.Value = modulator_frequency;
                East.carrier = East.carrier .* 0;
                East.modulator = East.modulator .* 0;
                East.stimulus = East.stimulus .* 0;
                East.stimulus_trigger = East.stimulus_trigger .* 0;
                East.pulse_trigger = East.pulse_trigger .* 0;
            else
                app.East_current_carrier.Value = carrier_frequency;
                app.East_current_modulator.Value = modulator_frequency;
                app.West_current_carrier.Value = carrier_frequency;
                app.West_current_modulator.Value = modulator_frequency;
            end
            time = East.time;
            carrier = cat(1, East.carrier, West.carrier);
            modulator = cat(1, East.modulator, West.modulator);
            East.stimulus = East.stimulus.*app.attenuate(app.EastAttenuatorSlider.Value);
            West.stimulus = West.stimulus.*app.attenuate(app.WestAttenuatorSlider.Value);
            stimulus = cat(1, East.stimulus, West.stimulus);
            stimulus_trigger = cat(1, East.stimulus_trigger, West.stimulus_trigger);
            pulse_trigger = cat(1, East.pulse_trigger, West.pulse_trigger);
        end
        
        function e = transfer_search_parameters(app, stimulus, stimulus_trigger, pulse_trigger)
            e = [];
            e(end+1) = app.RPcontroller.WriteTagV('EastPulseTrigger', 0, pulse_trigger(1, :));
            e(end+1) = app.RPcontroller.WriteTagV('EastStimulusTrigger', 0, stimulus_trigger(1, :));
            e(end+1) = app.RPcontroller.WriteTagV('EastStimulus', 0, stimulus(1, :));
            e(end+1) = app.RPcontroller.SetTagVal('EastStimulusPoints', size(stimulus, 2));
            e(end+1) = app.RPcontroller.WriteTagV('WestPulseTrigger', 0, pulse_trigger(2, :));
            e(end+1) = app.RPcontroller.WriteTagV('WestStimulusTrigger', 0, stimulus_trigger(2, :));
            e(end+1) = app.RPcontroller.WriteTagV('WestStimulus', 0, stimulus(2, :));
            e(end+1) = app.RPcontroller.SetTagVal('WestStimulusPoints', size(stimulus, 2));
            e = prod(e, 'all');
            if e
                app.LoadParametersLamp.Color = [0, 1, 0];
            else
                app.LoadParametersLamp.Color = [1, 0, 0];
            end
        end
        
        function [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = get_fft(app, time, carrier, modulator, stimulus, max_fc, max_fm)
            [~, carrier_fft] = app.compute_fft(carrier, time);
            [~, modulator_fft] = app.compute_fft(modulator, time);
            [n, stimulus_fft] = app.compute_fft(stimulus, time);
            freq = 0:(app.sampling_rate/n):(app.sampling_rate/2 - app.sampling_rate/n);
            carrier_freq = freq(freq <= (max_fc+200));
            modulator_freq = freq(freq <= (max_fm+20));
            carrier_fft = carrier_fft(:, 1:size(carrier_freq, 2));
            modulator_fft = modulator_fft(:, 1:size(modulator_freq, 2));
            stimulus_fft = stimulus_fft(:, 1:size(carrier_freq, 2));
        end
        
        function search_plot_stimuli(app, time, carrier, modulator, stimulus, max_fc, max_fm)
            plot_type = app.East_plot_type.Value;
            if plot_type == "Time"
                plot(app.East_carrier_plot, carrier(1, :), 'LineWidth', 0.5, "XData", time);
                plot(app.East_modulator_plot, modulator(1, :), 'LineWidth', 0.5, "XData", time);
                plot(app.East_stimulus_plot, stimulus(1, :), 'LineWidth', 0.5, "XData", time);
            elseif plot_type == "FFT"
                [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = app.get_fft(time, carrier(1, :), modulator(1, :), stimulus(1, :), max_fc, max_fm);
                plot(app.East_carrier_plot, carrier_fft, 'LineWidth', 0.5, "XData", carrier_freq);
                plot(app.East_modulator_plot, modulator_fft, 'LineWidth', 0.5, "XData", modulator_freq);
                plot(app.East_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", carrier_freq);
            end
            plot_type = app.West_plot_type.Value;
            if plot_type == "Time"
                plot(app.West_carrier_plot, carrier(2, :), 'LineWidth', 0.5, "XData", time);
                plot(app.West_modulator_plot, modulator(2, :), 'LineWidth', 0.5, "XData", time);
                plot(app.West_stimulus_plot, stimulus(2, :), 'LineWidth', 0.5, "XData", time);
            elseif plot_type == "FFT"
                [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = app.get_fft(time, carrier(2, :), modulator(2, :), stimulus(2, :), max_fc, max_fm);
                plot(app.West_carrier_plot, carrier_fft, 'LineWidth', 0.5, "XData", carrier_freq);
                plot(app.West_modulator_plot, modulator_fft, 'LineWidth', 0.5, "XData", modulator_freq);
                plot(app.West_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", carrier_freq);
            end
        end


        %% PRR Tab Functions
        function freq = get_PRR_modulator(app)
            if app.PRR_mode.Value == "Select"
                f = [];
                if app.Fm_PRR_1.Enable == 1
                    f(end+1) = app.Fm_PRR_1.Value;
                end
                if app.Fm_PRR_2.Enable == 1
                    f(end+1) = app.Fm_PRR_2.Value;
                end
                if app.Fm_PRR_3.Enable == 1
                    f(end+1) = app.Fm_PRR_3.Value;
                end
                if app.Fm_PRR_4.Enable == 1
                    f(end+1) = app.Fm_PRR_4.Value;
                end
                if app.Fm_PRR_5.Enable == 1
                    f(end+1) = app.Fm_PRR_5.Value;
                end
                freq.west = f;
                freq.east = f;
            elseif app.PRR_mode.Value == "Cycle"
                f = app.Fm_PRR_start.Value: app.Fm_PRR_step.Value: app.Fm_PRR_end.Value;
                freq.west = f;
                freq.east = f;
            elseif app.PRR_mode.Value == "Independent"
                freq.west = app.West_PRR_Pulse_rate.Value;
                freq.east = app.East_PRR_Pulse_rate.Value;
            end
        end
        
        function [f, A] = get_nonIndependent_PRR_fcs_A(app)
            f = [];
            A = []; 
            if app.Fc_PRR_1.Enable == 1
                f(end+1) = app.Fc_PRR_1.Value;
                A(end+1) = app.Ec_PRR_1.Value;
            end
            if app.Fc_PRR_2.Enable == 1
                f(end+1) = app.Fc_PRR_2.Value;
                A(end+1) = app.Ec_PRR_2.Value;
            end
            if app.Fc_PRR_3.Enable == 1
                f(end+1) = app.Fc_PRR_3.Value;
                A(end+1) = app.Ec_PRR_3.Value;
            end
            if app.Fc_PRR_4.Enable == 1
                f(end+1) = app.Fc_PRR_4.Value;
                A(end+1) = app.Ec_PRR_4.Value;
            end
            if app.Fc_PRR_5.Enable == 1
                f(end+1) = app.Fc_PRR_5.Value;
                A(end+1) = app.Ec_PRR_5.Value;
            end
        end
        
        function [rise_time, on_pulse, off_pulse] = build_triangular_modulator(app, rt, pulse_on_duration, pulse_off_duration)
            if rt.units == "%"
                rise_time = pulse_on_duration*(rt.value/100);
            elseif rt.units == "msec"
                rise_time = min(pulse_on_duration, rt.value*1e-3);
            end
            fall_time = pulse_on_duration - rise_time;
            time = 0: (1/app.sampling_rate): (rise_time - (1/app.sampling_rate));
            rise_pulse = (1/rise_time).*time;
            time = rise_time: (1/app.sampling_rate): (pulse_on_duration - (1/app.sampling_rate));
            fall_pulse = ((-1/fall_time).*(time-rise_time)) + 1;
            on_pulse = cat(2, rise_pulse, fall_pulse);
            off_pulse = zeros(1, floor(pulse_off_duration*app.sampling_rate));
        end
        
        function  [on_pulse, off_pulse] = build_exponential_modulator(app, pulse_on_duration, pulse_off_duration)
            time = 0: (1/app.sampling_rate): (pulse_on_duration - (1/app.sampling_rate));
            tau = pulse_on_duration/10;
            exponent = time./tau;
            on_pulse = exponent.*exp(-1.*exponent + 1);
            off_pulse = zeros(1, floor(pulse_off_duration*app.sampling_rate));
        end
        
        function [on_pulse, off_pulse] = build_rectangular_modulator(app, pulse_on_duration, pulse_off_duration)
            time = 0: (1/app.sampling_rate): pulse_on_duration-(1/app.sampling_rate);
            on_pulse = ones(size(time));
            time = 0: (1/app.sampling_rate): pulse_off_duration-(1/app.sampling_rate);
            off_pulse = zeros(size(time));
        end

        function [on_pulse, off_pulse] = build_natural_pulse(app, pulse_duration)
            species = app.SpeciesDropDown.Value;
            if species == "Rana pipiens"
                call_pulses_index = find(strcmp(app.call_pulses_list.Rpipiens, app.NaturalPulseDropDown.Value));
                on_pulse = app.call_pulses.Rpipiens(call_pulses_index, 1);
            elseif species == "Hyla versicolor"
                call_pulses_index = find(strcmp(app.call_pulses_list.Hversicolor, app.NaturalPulseDropDown.Value));
                on_pulse = app.call_pulses.Hversicolor(call_pulses_index, 1);
            elseif species == "Hyla chrysoscelis"
                call_pulses_index = find(strcmp(app.call_pulses_list.Hchrysoscelis, app.NaturalPulseDropDown.Value));
                on_pulse = app.call_pulses.Hchrysoscelis(call_pulses_index, 1);
            elseif species == "Pseudacris feriarum"
                call_pulses_index = find(strcmp(app.call_pulses_list.Pferiarum, app.NaturalPulseDropDown.Value));
                on_pulse = app.call_pulses.Pferiarum(call_pulses_index, 1);
            end
            on_pulse = cell2mat(on_pulse);
            on_pulse = on_pulse - on_pulse(:, 1);
            pulse_samples = floor(pulse_duration*app.sampling_rate);
            if pulse_samples < size(on_pulse, 2)
                error("Pulse rate is too fast for the natural pulse!");
            end
            off_pulse = zeros(size(on_pulse, 1), pulse_samples-size(on_pulse, 2));
        end
        
        function [pulse_on_duration, rise_time, carrier, pulses, triggers] = build_PRR_pulse_train(app, fm, npulses, pd, rt, gap, carrier_cfg, masker_mode)
            %% Modulator
            % Indicator (In Pulse / Not In Pulse) Envelope
            total_pulse_duration = floor(1000.0/fm)*1e-3;
            if pd.units == "%"
                pulse_on_duration = total_pulse_duration*(pd.value/100);
            elseif pd.units == "msec"
                pulse_on_duration = min(total_pulse_duration, pd.value*1e-3);
            end
            pulse_off_duration = total_pulse_duration - pulse_on_duration;
            envelope = app.PRREnvelope.Value;

            % Intra-pulse Shape
            if envelope == "Triangular"
                [rise_time, on_pulse, off_pulse] = app.build_triangular_modulator(rt, pulse_on_duration, pulse_off_duration);
            elseif envelope == "Exponential"
                [on_pulse, off_pulse] = app.build_exponential_modulator(pulse_on_duration, pulse_off_duration);
                rise_time = 0;
            elseif envelope == "Rectangular"
                [on_pulse, off_pulse] = app.build_rectangular_modulator(pulse_on_duration, pulse_off_duration);
                rise_time = 0;
            elseif envelope == "Natural"
                [on_pulse, off_pulse] = app.build_natural_pulse(total_pulse_duration);
                rise_time = 0;
            end

            % Create Sinlgle-Pulse Envelope
            pulse = cat(2, on_pulse, off_pulse);
            
            % Initialize Triggers
            trigger = zeros(1, size(pulse, 2));
            trigger(1, 1) = 1;
            pulses = [];
            triggers = [];
            carrier = [];
            t = 0:(1/app.sampling_rate):((size(on_pulse, 2)-1)/app.sampling_rate);

            %% Carrier Sound
            % Standard Carrier
            if strcmp(masker_mode, "None")
                carrier_pulse = cat(2, app.build_tonal_carrier(t, carrier_cfg), off_pulse);
            
            % Information Masking
            elseif strcmp(masker_mode, "BP Energy")
                noise = randn(1, length(t));
                carrier_pulse = cat(2, filtfilt(app.masker.bp_filt, noise), off_pulse);
            elseif strcmp(masker_mode, "AP Energy")
                noise = randn(length(t), 1);
                carrier_pulse = cat(2, filtfilt(app.masker.ap_filt, noise), off_pulse);
            elseif strcmp(masker_mode, "BP Carrier")
                carrier_pulse = cat(2, sin(2*pi*app.Fc_5.Value.*t), off_pulse);
            elseif strcmp(masker_mode, "White Noise")
                noise = randn(1, length(t));
                carrier_pulse = cat(2, noise, off_pulse);
            elseif strcmp(masker_mode, "BP Cont") || strcmp(masker_mode, "AP Cont")
                carrier_pulse = cat(2, zeros(size(t)), off_pulse);
            end
            for i = 1: 1: npulses
                if strcmp(masker_mode, "BP Info")
                    idx = randi(length(app.masker.bp_carrier_list));
                    fc = app.masker.bp_carrier_list(idx);
                    carrier_pulse = cat(2, sin(2*pi*fc.*t), off_pulse);
                elseif strcmp(masker_mode, "AP Info")
                    idx = randi(length(app.masker.ap_carrier_list));
                    fc = app.masker.ap_carrier_list(idx);
                    carrier_pulse = cat(2, sin(2*pi*fc.*t), off_pulse);
                end

                % Multi-gap adds delay after every nth pulse. Non-multi
                % adds delay after the "first" nth pulse.
                if gap.multi == 0 % Single-gap mode
                    gap_cond = (i == gap.after_pulse);
                elseif gap.multi == 1 % Multi-gap mode
                    gap_cond = (mod(i, gap.after_pulse) == 0);
                end
                if gap_cond % Add gap after i-th pulse
                    pulses = cat(2, pulses, pulse, zeros(1, floor(gap.time*app.sampling_rate)));
                    triggers = cat(2, triggers, trigger, zeros(1, floor(gap.time*app.sampling_rate)));
                    carrier = cat(2, carrier, carrier_pulse, zeros(1, floor(gap.time*app.sampling_rate)));
                else
                    pulses = cat(2, pulses, pulse);
                    triggers = cat(2, triggers, trigger);
                    carrier = cat(2, carrier, carrier_pulse);
                end
            end
            if strcmp(masker_mode, "BP Cont")
                noise = randn(1, length(carrier));
                carrier = filtfilt(app.masker.bp_filt, noise);
            elseif strcmp(masker_mode, "AP Cont")
                noise = randn(1, length(carrier));
                carrier = filtfilt(app.masker.ap_filt, noise);
            end
        end
        
        function [times, carriers, modulators, stimuli, pulse_triggers, stimulus_triggers] = build_PRR_stimulus(app, fm_east, fm_west)
            %% WEST SPEAKER
            pd.units = app.West_PRR_PD_units.Value;
            pd.value = app.West_PRR_PD.Value;
            rt.units = app.West_PRR_RT_units.Value;
            rt.value = app.West_PRR_RT.Value;
            if app.PRR_mode.Value == "Independent"
                gap.after_pulse = app.West_PRR_Gap_after_pulse.Value;
                gap.time = app.West_PRR_Gap_time.Value*1e-3;
                gap.multi = app.West_PRR_multiGap_check.Value;
                carrier_cfg.fcs = [app.West_PRR_Carrier.Value];
                carrier_cfg.A = 1;
            else
                gap.after_pulse = 0;
                gap.time = 0;
                gap.multi = 0;
                [carrier_cfg.fcs, carrier_cfg.A] = app.get_nonIndependent_PRR_fcs_A();

            end
            if app.West_PRR_nPulses.Enable == 1
                npulses = app.West_PRR_nPulses.Value;
            else
               npulses = floor((fm_west/1000)*400); 
            end
            [pulse_on_duration, rise_time, carriers.west, modulators.west, pulse_triggers.west] = app.build_PRR_pulse_train(fm_west, npulses, pd, rt, gap, carrier_cfg, app.WestMasker.Value);
            if app.PRREnvelope.Value == "Natural"
                carriers.west = (carriers.west.*0) + 1;
            end
            if app.West_PRR_PD_units.Value == "msec"
                app.West_PRR_PD.Value = pulse_on_duration*1e3;
            end
            if app.West_PRR_RT_units.Value == "msec"
                app.West_PRR_RT.Value = rise_time*1e3;
            end
            %% EAST SPEAKER
            pd.units = app.East_PRR_PD_units.Value;
            pd.value = app.East_PRR_PD.Value;
            rt.units = app.East_PRR_RT_units.Value;
            rt.value = app.East_PRR_RT.Value;
            if app.East_PRR_nPulses.Enable == 1
                npulses = app.East_PRR_nPulses.Value;
            else
               npulses = floor((fm_east/1000)*400); 
            end
            if app.PRR_mode.Value == "Independent"
                gap.after_pulse = app.East_PRR_Gap_after_pulse.Value;
                gap.time = app.East_PRR_Gap_time.Value*1e-3;
                gap.multi = app.East_PRR_multiGap_check.Value;
                carrier_cfg.fcs = [app.East_PRR_Carrier.Value];
                carrier_cfg.A = 1;
            else
                gap.after_pulse = 0;
                gap.time = 0;
                gap.multi = 0;
                [carrier_cfg.fcs, carrier_cfg.A] = app.get_nonIndependent_PRR_fcs_A();
            end
            [pulse_on_duration, rise_time, carriers.east, modulators.east, pulse_triggers.east] = app.build_PRR_pulse_train(fm_east, npulses, pd, rt, gap, carrier_cfg, app.EastMasker.Value);
            if app.PRREnvelope.Value == "Natural"
                carriers.east = (carriers.east.*0) + 1;
            end
            if app.East_PRR_PD_units.Value == "msec"
                app.East_PRR_PD.Value = pulse_on_duration*1e3;
            end
            if app.East_PRR_RT_units.Value == "msec"
                app.East_PRR_RT.Value = rise_time*1e3;
            end
            if strcmp(app.WestMasker.Value, "BP Cont") || strcmp(app.WestMasker.Value, "AP Cont")
                stimuli.west = carriers.west;
            else
                stimuli.west = modulators.west.*carriers.west;
            end
            stimulus_triggers.west = zeros(size(stimuli.west));
            stimulus_triggers.west(1,1) = 1;
            if strcmp(app.EastMasker.Value, "BP Cont") || strcmp(app.EastMasker.Value, "AP Cont")
                stimuli.east = carriers.east;
            else
                stimuli.east = modulators.east.*carriers.east;
            end
            stimulus_triggers.east = zeros(size(stimuli.east));
            stimulus_triggers.east(1,1) = 1;
            stimuli.east = stimuli.east.*app.attenuate(app.EastAttenuatorSlider.Value);
            stimuli.west = stimuli.west.*app.attenuate(app.WestAttenuatorSlider.Value);
            if app.Speaker.Value == "Biaural"
                speaker_interval = app.Inter_speaker_interval.Value * 1e-3;
                speaker_interval_samples = zeros(1, floor(abs(speaker_interval)*app.sampling_rate));
                if speaker_interval >= 0
                   modulators.east = cat(2, speaker_interval_samples, modulators.east);
                   carriers.east = cat(2, speaker_interval_samples, carriers.east);
                   pulse_triggers.east = cat(2, speaker_interval_samples, pulse_triggers.east);
                   stimuli.east = cat(2, speaker_interval_samples, stimuli.east);
                   stimulus_triggers.east = cat(2, speaker_interval_samples, stimulus_triggers.east);
                   modulators.west = cat(2, modulators.west, speaker_interval_samples);
                   carriers.west = cat(2, carriers.west, speaker_interval_samples);
                   pulse_triggers.west = cat(2, pulse_triggers.west, speaker_interval_samples);
                   stimuli.west = cat(2, stimuli.west, speaker_interval_samples);
                   stimulus_triggers.west = cat(2, stimulus_triggers.west, speaker_interval_samples);
                else
                   modulators.west = cat(2, speaker_interval_samples, modulators.west);
                   carriers.west = cat(2, speaker_interval_samples, carriers.west);
                   pulse_triggers.west = cat(2, speaker_interval_samples, pulse_triggers.west);
                   stimuli.west = cat(2, speaker_interval_samples, stimuli.west);
                   stimulus_triggers.west = cat(2, speaker_interval_samples, stimulus_triggers.west);
                   modulators.east = cat(2, modulators.east, speaker_interval_samples);
                   carriers.east = cat(2, carriers.east, speaker_interval_samples);
                   pulse_triggers.east = cat(2, pulse_triggers.east, speaker_interval_samples);
                   stimuli.east = cat(2, stimuli.east, speaker_interval_samples);
                   stimulus_triggers.east = cat(2, stimulus_triggers.east, speaker_interval_samples);
                end
                app.East_current_modulator.Value = fm_east;
                app.West_current_modulator.Value = fm_west;
            end
            if app.Speaker.Value == "West"
                if app.CombineOutputCheckBox.Value == 1
                    speaker_interval = app.Inter_speaker_interval.Value * 1e-3;
                    speaker_interval_samples = zeros(1, floor(abs(speaker_interval)*app.sampling_rate));
                    if speaker_interval >= 0
                       modulators.east = cat(2, speaker_interval_samples, modulators.east);
                       carriers.east = cat(2, speaker_interval_samples, carriers.east);
                       pulse_triggers.east = cat(2, speaker_interval_samples, pulse_triggers.east);
                       stimuli.east = cat(2, speaker_interval_samples, stimuli.east);
                       stimulus_triggers.east = cat(2, speaker_interval_samples, stimulus_triggers.east);
                       modulators.west = cat(2, modulators.west, speaker_interval_samples);
                       carriers.west = cat(2, carriers.west, speaker_interval_samples);
                       pulse_triggers.west = cat(2, pulse_triggers.west, speaker_interval_samples);
                       stimuli.west = cat(2, stimuli.west, speaker_interval_samples);
                       stimulus_triggers.west = cat(2, stimulus_triggers.west, speaker_interval_samples);
                    else
                       modulators.west = cat(2, speaker_interval_samples, modulators.west);
                       carriers.west = cat(2, speaker_interval_samples, carriers.west);
                       pulse_triggers.west = cat(2, speaker_interval_samples, pulse_triggers.west);
                       stimuli.west = cat(2, speaker_interval_samples, stimuli.west);
                       stimulus_triggers.west = cat(2, speaker_interval_samples, stimulus_triggers.west);
                       modulators.east = cat(2, modulators.east, speaker_interval_samples);
                       carriers.east = cat(2, carriers.east, speaker_interval_samples);
                       pulse_triggers.east = cat(2, pulse_triggers.east, speaker_interval_samples);
                       stimuli.east = cat(2, stimuli.east, speaker_interval_samples);
                       stimulus_triggers.east = cat(2, stimulus_triggers.east, speaker_interval_samples);
                    end
                    app.East_current_modulator.Value = fm_east;
                    app.West_current_modulator.Value = fm_west;
                    if size(stimuli.west, 2) < size(stimuli.east, 2)
                        extra_samples = size(stimuli.east, 2) - size(stimuli.west, 2);
                        stimuli.west = cat(2, stimuli.west, zeros(size(stimuli.west, 1), extra_samples));
                        carriers.west = cat(2, carriers.west, zeros(size(carriers.west, 1), extra_samples));
                        modulators.west = cat(2, modulators.west, zeros(size(modulators.west, 1), extra_samples));
                        pulse_triggers.west = cat(2, pulse_triggers.west, zeros(size(pulse_triggers.west, 1), extra_samples));
                        stimulus_triggers.west = cat(2, stimulus_triggers.west, zeros(size(stimulus_triggers.west, 1), extra_samples));
                    elseif size(stimuli.west, 2) > size(stimuli.east, 2)
                        extra_samples = size(stimuli.east, 2) - size(stimuli.west, 2);
                        stimuli.east = cat(2, stimuli.east, zeros(size(stimuli.east, 1), extra_samples));
                        carriers.east = cat(2, carriers.east, zeros(size(carriers.east, 1), extra_samples));
                        modulators.east = cat(2, modulators.east, zeros(size(modulators.east, 1), extra_samples));
                        pulse_triggers.east = cat(2, pulse_triggers.east, zeros(size(pulse_triggers.east, 1), extra_samples));
                        stimulus_triggers.east = cat(2, stimulus_triggers.east, zeros(size(stimulus_triggers.east, 1), extra_samples));
                    end
                    stimuli.west = stimuli.east+stimuli.west;
                    stimuli.east = stimuli.east.*0;
                else
                    carriers.east = carriers.east * 0;
                    modulators.east = modulators.east * 0;
                    pulse_triggers.east = pulse_triggers.east * 0;
                    stimuli.east = stimuli.east * 0;
                    stimulus_triggers.east = stimulus_triggers.east * 0;
                    app.East_current_modulator.Value = 0;
                    app.West_current_modulator.Value = fm_west;
                end
            elseif app.Speaker.Value == "East"
                if app.CombineOutputCheckBox.Value == 1
                    speaker_interval = app.Inter_speaker_interval.Value * 1e-3;
                    speaker_interval_samples = zeros(1, floor(abs(speaker_interval)*app.sampling_rate));
                    if speaker_interval >= 0
                       modulators.east = cat(2, speaker_interval_samples, modulators.east);
                       carriers.east = cat(2, speaker_interval_samples, carriers.east);
                       pulse_triggers.east = cat(2, speaker_interval_samples, pulse_triggers.east);
                       stimuli.east = cat(2, speaker_interval_samples, stimuli.east);
                       stimulus_triggers.east = cat(2, speaker_interval_samples, stimulus_triggers.east);
                       modulators.west = cat(2, modulators.west, speaker_interval_samples);
                       carriers.west = cat(2, carriers.west, speaker_interval_samples);
                       pulse_triggers.west = cat(2, pulse_triggers.west, speaker_interval_samples);
                       stimuli.west = cat(2, stimuli.west, speaker_interval_samples);
                       stimulus_triggers.west = cat(2, stimulus_triggers.west, speaker_interval_samples);
                    else
                       modulators.west = cat(2, speaker_interval_samples, modulators.west);
                       carriers.west = cat(2, speaker_interval_samples, carriers.west);
                       pulse_triggers.west = cat(2, speaker_interval_samples, pulse_triggers.west);
                       stimuli.west = cat(2, speaker_interval_samples, stimuli.west);
                       stimulus_triggers.west = cat(2, speaker_interval_samples, stimulus_triggers.west);
                       modulators.east = cat(2, modulators.east, speaker_interval_samples);
                       carriers.east = cat(2, carriers.east, speaker_interval_samples);
                       pulse_triggers.east = cat(2, pulse_triggers.east, speaker_interval_samples);
                       stimuli.east = cat(2, stimuli.east, speaker_interval_samples);
                       stimulus_triggers.east = cat(2, stimulus_triggers.east, speaker_interval_samples);
                    end
                    app.East_current_modulator.Value = fm_east;
                    app.West_current_modulator.Value = fm_west;
                    if size(stimuli.west, 2) < size(stimuli.east, 2)
                        stimuli.east = stimuli.east(:, 1:size(stimuli.west, 2));
                        carriers.east = carriers.east(:, 1:size(carriers.west, 2));
                        modulators.east = modulators.east(:, 1:size(modulators.west, 2));
                        pulse_triggers.east = pulse_triggers.east(:, 1:size(pulse_triggers.west, 2));
                        stimulus_triggers.east = stimulus_triggers.east(:, 1:size(stimulus_triggers.west, 2));
                    elseif size(stimuli.west, 2) > size(stimuli.east, 2)
                        stimuli.west = stimuli.west(:, 1:size(stimuli.east, 2));
                        carriers.west = carriers.west(:, 1:size(carriers.east, 2));
                        modulators.west = modulators.west(:, 1:size(modulators.east, 2));
                        pulse_triggers.west = pulse_triggers.west(:, 1:size(pulse_triggers.east, 2));
                        stimulus_triggers.west = stimulus_triggers.west(:, 1:size(stimulus_triggers.east, 2));
                    end
                    stimuli.east = stimuli.east+stimuli.west;
                    stimuli.west = stimuli.west.*0;
                else
                    carriers.west = carriers.west * 0;
                    modulators.west = modulators.west * 0;
                    pulse_triggers.west = pulse_triggers.west * 0;
                    stimuli.west = stimuli.west * 0;
                    stimulus_triggers.west = stimulus_triggers.west * 0;
                    app.East_current_modulator.Value = fm_east;
                    app.West_current_modulator.Value = 0;
                end
            end
            times.west = 0: (1/app.sampling_rate): ((size(modulators.west, 2)-1)/app.sampling_rate);
            times.east = 0: (1/app.sampling_rate): ((size(modulators.east, 2)-1)/app.sampling_rate);
        end
        
        function e = transfer_PRR_parameters(app, stimulus, stimulus_trigger, pulse_trigger)
            e = [];
            e(end+1) = app.RPcontroller.WriteTagV('EastPulseTrigger', 0, pulse_trigger.east);
            e(end+1) = app.RPcontroller.WriteTagV('EastStimulusTrigger', 0, stimulus_trigger.east);
            e(end+1) = app.RPcontroller.WriteTagV('EastStimulus', 0, stimulus.east);
            e(end+1) = app.RPcontroller.SetTagVal('EastStimulusPoints', size(stimulus.east, 2));
            e(end+1) = app.RPcontroller.WriteTagV('WestPulseTrigger', 0, pulse_trigger.west);
            e(end+1) = app.RPcontroller.WriteTagV('WestStimulusTrigger', 0, stimulus_trigger.west);
            e(end+1) = app.RPcontroller.WriteTagV('WestStimulus', 0, stimulus.west);
            e(end+1) = app.RPcontroller.SetTagVal('WestStimulusPoints', size(stimulus.west, 2));
            e = prod(e, 'all');
            if e
                app.LoadParametersLamp.Color = [0, 1, 0];
            else
                app.LoadParametersLamp.Color = [1, 0, 0];
            end
        end
        
        function plot_PRR(app, times, carriers, modulators, stimuli, max_fc, max_fm)
            plot_type = app.East_plot_type.Value;
            if plot_type == "Time"
                plot(app.East_carrier_plot, carriers.east, 'LineWidth', 0.5, "XData", times.east);
                plot(app.East_modulator_plot, modulators.east, 'LineWidth', 0.5, "XData", times.east);
                plot(app.East_stimulus_plot, stimuli.east, 'LineWidth', 0.5, "XData", times.east);
            elseif plot_type == "FFT"
                [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = app.get_fft(times.east, carriers.east, modulators.east, stimuli.east, max_fc, max_fm.east);
                plot(app.East_carrier_plot, carrier_fft, 'LineWidth', 0.5, "XData", carrier_freq);
                plot(app.East_modulator_plot, modulator_fft, 'LineWidth', 0.5, "XData", modulator_freq);
                plot(app.East_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", carrier_freq);
            end
            plot_type = app.West_plot_type.Value;
            if plot_type == "Time"
                plot(app.West_carrier_plot, carriers.west, 'LineWidth', 0.5, "XData", times.west);
                plot(app.West_modulator_plot, modulators.west, 'LineWidth', 0.5, "XData", times.west);
                plot(app.West_stimulus_plot, stimuli.west, 'LineWidth', 0.5, "XData", times.west);
            elseif plot_type == "FFT"
                [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = app.get_fft(times.west, carriers.west, modulators.west, stimuli.west, max_fc, max_fm.west);
                plot(app.West_carrier_plot, carrier_fft, 'LineWidth', 0.5, "XData", carrier_freq);
                plot(app.West_modulator_plot, modulator_fft, 'LineWidth', 0.5, "XData", modulator_freq);
                plot(app.West_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", carrier_freq);
            end
        end

        function natural_pulses_initialization(app)
            DIR = "./callExtracts/Rpipiens/";
            listing = dir(DIR);
            listing = listing(3:end);
            app.call_pulses_list.Rpipiens = strings(length(listing), 1);
            app.call_pulses.Rpipiens = cell(length(listing), 1);
            for i = 1: 1: length(listing)
                app.call_pulses_list.Rpipiens{i, 1} = listing(i).name;
                [call, ~] = audioread(strcat(DIR, listing(i).name));
                app.call_pulses.Rpipiens{i, 1} = app.audio_norm(call');
            end

            DIR = "./callExtracts/Hversicolor/";
            listing = dir(DIR);
            listing = listing(3:end);
            app.call_pulses_list.Hversicolor = strings(length(listing), 1);
            app.call_pulses.Hversicolor = cell(length(listing), 1);
            for i = 1: 1: length(listing)
                app.call_pulses_list.Hversicolor{i, 1} = listing(i).name;
                [call, ~] = audioread(strcat(DIR, listing(i).name));
                app.call_pulses.Hversicolor{i, 1} = app.audio_norm(call');
            end

            DIR = "./callExtracts/Hchrysoscelis/";
            listing = dir(DIR);
            listing = listing(3:end);
            app.call_pulses_list.Hchrysoscelis = strings(length(listing), 1);
            app.call_pulses.Hchrysoscelis = cell(length(listing), 1);
            for i = 1: 1: length(listing)
                app.call_pulses_list.Hchrysoscelis{i, 1} = listing(i).name;
                [call, ~] = audioread(strcat(DIR, listing(i).name));
                app.call_pulses.Hchrysoscelis{i, 1} = app.audio_norm(call');
            end

            DIR = "./callExtracts/Pferiarum/";
            listing = dir(DIR);
            listing = listing(3:end);
            app.call_pulses_list.Pferiarum = strings(length(listing), 1);
            app.call_pulses.Pferiarum = cell(length(listing), 1);
            for i = 1: 1: length(listing)
                app.call_pulses_list.Pferiarum{i, 1} = listing(i).name;
                [call, ~] = audioread(strcat(DIR, listing(i).name));
                app.call_pulses.Pferiarum{i, 1} = app.audio_norm(call');
            end
        end

        
        %% Natural Calls Tab Functions
        function abbrev = species_abbreviation(~, species_name)
            M = dictionary( ...
                ["Rana pipiens", "Hyla versicolor", "Hyla chrysoscelis", "Pseudacris feriarum"], ...
                ["Rpipiens",     "Hversicolor",    "Hchrysoscelis",    "Pferiarum"] ...
            );
            if isKey(M, species_name)
                abbrev = M(species_name);
            else
                abbrev = species_name;
            end
        end

        function [stimulus, stimulus_triggers, pulse_triggers, stimulus_time] = build_natural_call(app)
            species = app.SpeciesDropDown.Value;
            abbrev = app.species_abbreviation(species);
            call_index = strcmp(app.calls_list.(abbrev), app.NaturalCall.Value);
            carrier_type = app.NaturalCarrier.Value;
            if strcmp(carrier_type, "Natural")
                call = app.calls.(abbrev)(call_index, 1);
            else % other options involve fetching envelope
                envelope = app.call_envelopes.(abbrev)(call_index, 1);
                nsamples = size(envelope{1}, 2);
                if strcmp(carrier_type, "Tone")
                    carrier_cfg.fcs = [app.NaturalToneHz.Value];
                    carrier_cfg.A = 1;
                    t = 0:(1/app.sampling_rate):((nsamples-1)/app.sampling_rate);
                    tone = app.build_tonal_carrier(t, carrier_cfg);
                    call = envelope{1}.*tone;
                elseif strcmp(carrier_type, "White Noise")
                    noise = randn(1, nsamples);
                    call = envelope{1}.*noise;
                else % Shouldn't be possible -- all options covered in ifs
                    msg = string(sprintf("No carrier option %s", carrier_type));
                    error("build_natural_call:InvalidValue", msg)
                end
            end

            if iscell(call)
                call = cell2mat(call);
            end
            if app.NaturalPlayMode.Value == "Reversed"
                call = fliplr(call);
            end

            stimulus.east = call.*app.attenuate(app.EastAttenuatorSlider.Value);
            stimulus.west = call.*app.attenuate(app.WestAttenuatorSlider.Value);

            stimulus_triggers.east = zeros(size(stimulus.east));
            pulse_triggers.east = zeros(size(stimulus.east));

            stimulus_triggers.west = zeros(size(stimulus.west));
            pulse_triggers.west = zeros(size(stimulus.west));

            speaker_interval = app.Inter_speaker_interval.Value * 1e-3;
            speaker_interval_samples = zeros(1, floor(abs(speaker_interval)*app.sampling_rate));
            stimulus_triggers.east(1, 1) = 1;
            stimulus_triggers.west(1, 1) = 1;

            if speaker_interval > 0
                stimulus.east = cat(2, speaker_interval_samples, stimulus.east);
                stimulus_triggers.east = cat(2, speaker_interval_samples, stimulus_triggers.east);
                pulse_triggers.east = cat(2, speaker_interval_samples, pulse_triggers.east);
                stimulus.west = cat(2, stimulus.west, speaker_interval_samples);
                stimulus_triggers.west = cat(2, stimulus_triggers.west, speaker_interval_samples);
                pulse_triggers.west = cat(2, pulse_triggers.west, speaker_interval_samples);
            elseif speaker_interval < 0
                stimulus.west = cat(2, speaker_interval_samples, stimulus.west);
                stimulus_triggers.west = cat(2, speaker_interval_samples, stimulus_triggers.west);
                pulse_triggers.west = cat(2, speaker_interval_samples, pulse_triggers.west);
                stimulus.east = cat(2, stimulus.east, speaker_interval_samples);
                stimulus_triggers.east = cat(2, stimulus_triggers.east, speaker_interval_samples);
                pulse_triggers.east = cat(2, pulse_triggers.east, speaker_interval_samples);
            end
            if app.Speaker.Value == "East"
                stimulus.west = stimulus.west .* 0;
                stimulus_triggers.west = stimulus_triggers.west .* 0;
                pulse_triggers.west = pulse_triggers.west .* 0;
            elseif app.Speaker.Value == "West"
                stimulus.east = stimulus.east .* 0;
                stimulus_triggers.east = stimulus_triggers.east .* 0;
                pulse_triggers.east = pulse_triggers.east .* 0;
            end
            stimulus_time.east = (0: 1: size(stimulus.east, 2)-1)/app.sampling_rate;
            stimulus_time.west = (0: 1: size(stimulus.west, 2)-1)/app.sampling_rate;
        end
        
        function [freq, stimulus_fft] = call_fft(app, stimulus, time)
            [n, stimulus_fft] = app.compute_fft(stimulus, time);
            freq = 0:(app.sampling_rate/n):(app.sampling_rate/2 - app.sampling_rate/n);
            if app.SpeciesDropDown.Value == "Rana pipiens"
                freq = freq(freq <= (1600+200));
            elseif app.SpeciesDropDown.Value == "Hyla versicolor"
                freq = freq(freq <= (2200+200));
            elseif app.SpeciesDropDown.Value == "Hyla chrysoscelis"
                freq = freq(freq <= (2200+200));
            elseif app.SpeciesDropDown.Value == "Pseudacris feriarum"
                freq = freq(freq <= (3100+500));
            end
            stimulus_fft = stimulus_fft(:, 1:size(freq, 2));
        end
        
        function plot_calls(app, stimulus, stimulus_time)
            plot_type = app.East_plot_type.Value;
            ylim = max([app.attenuate(app.EastAttenuatorSlider.Value) app.attenuate(app.WestAttenuatorSlider.Value)]);
            if plot_type == "Time"
                plot(app.East_carrier_plot, stimulus.east.*0, 'LineWidth', 0.5, "XData", stimulus_time.east);
                plot(app.East_modulator_plot, stimulus.east.*0, 'LineWidth', 0.5, "XData", stimulus_time.east);
                plot(app.East_stimulus_plot, stimulus.east, 'LineWidth', 0.5, "XData", stimulus_time.east);
                app.East_stimulus_plot.YLim = [-ylim ylim];
                app.East_stimulus_plot.YLimMode = 'auto';
            elseif plot_type == "FFT"
                [freq, stimulus_fft] = app.call_fft(stimulus.east, stimulus_time.east);
                plot(app.East_carrier_plot, stimulus_fft.*0, 'LineWidth', 0.5, "XData", freq);
                plot(app.East_modulator_plot, stimulus_fft.*0, 'LineWidth', 0.5, "XData", freq);
                plot(app.East_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", freq);
            end
            plot_type = app.West_plot_type.Value;
            if plot_type == "Time"
                plot(app.West_carrier_plot, stimulus.west.*0, 'LineWidth', 0.5, "XData", stimulus_time.west);
                plot(app.West_modulator_plot, stimulus.west.*0, 'LineWidth', 0.5, "XData", stimulus_time.west);
                plot(app.West_stimulus_plot, stimulus.west, 'LineWidth', 0.5, "XData", stimulus_time.west);
                app.West_stimulus_plot.YLim = [-ylim ylim];
                app.West_stimulus_plot.YLimMode = 'auto';
            elseif plot_type == "FFT"
                [freq, stimulus_fft] = app.call_fft(stimulus.west, stimulus_time.west);
                plot(app.West_carrier_plot, stimulus_fft.*0, 'LineWidth', 0.5, "XData", freq);
                plot(app.West_modulator_plot, stimulus_fft.*0, 'LineWidth', 0.5, "XData", freq);
                plot(app.West_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", freq);
            end
        end
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, debug)
            arguments
                app;
                debug(1, 1) logical {mustBeNumericOrLogical} = false;
            end
            app.debug = debug;
            if app.debug == false
                app.hardware_initialization();
            end
            app.reference_amplitude = 10;
            app.natural_calls_initialization();
            app.NaturalPulseDropDown.Enable = 0;
            app.natural_pulses_initialization();
            app.SpeciesDropDownValueChanged();
        end

        % Value changed function: SpeciesDropDown
        function SpeciesDropDownValueChanged(app, event)
            value = app.SpeciesDropDown.Value;
            if value == "Rana pipiens"
                app.Fc_5.Value = 1600;
                app.Fc_PRR_5.Value = 1600;
                app.NaturalCall.Items = app.calls_list.Rpipiens;
                app.NaturalPulseDropDown.Items = app.call_pulses_list.Rpipiens;
            elseif value == "Hyla versicolor"
                app.Fc_5.Value = 2200;
                app.Fc_PRR_5.Value = 2200;
                app.NaturalCall.Items = app.calls_list.Hversicolor;
                app.NaturalPulseDropDown.Items = app.call_pulses_list.Hversicolor;
            elseif value == "Hyla chrysoscelis"
                app.Fc_5.Value = 2200;
                app.Fc_PRR_5.Value = 2200;
                app.NaturalCall.Items = app.calls_list.Hchrysoscelis;
                app.NaturalPulseDropDown.Items = app.call_pulses_list.Hchrysoscelis;
            elseif value == "Pseudacris feriarum"
                app.Fc_1.Value = 200;
                app.Fc_PRR_1.Value = 200;
                app.Fc_2.Value = 400;
                app.Fc_PRR_2.Value = 400;
                app.Fc_3.Value = 700;
                app.Fc_PRR_3.Value = 700;
                app.Fc_4.Value = 1400; 
                app.Fc_PRR_4.Value = 1400;
                app.Fc_5.Value = 3100;
                app.Fc_PRR_5.Value = 3100;
                app.NaturalCall.Items = app.calls_list.Pferiarum;
                app.NaturalPulseDropDown.Items = app.call_pulses_list.Pferiarum;
            else
                msg = string(sprintf('Species %s not listed', value));
                error('SpeciesDropDownValueChanged:InvalidValue', msg)
            end
            app.masker.bp_carrier_list = app.Fc_5.Value-100:100:app.Fc_5.Value+600;
            app.masker.bp_filt = designfilt('bandpassiir', 'FilterOrder', 4, ...
               'HalfPowerFrequency1', app.masker.bp_carrier_list(1), 'HalfPowerFrequency2', app.masker.bp_carrier_list(end), ...
               'SampleRate', app.sampling_rate);
            app.masker.ap_carrier_list = app.Fc_4.Value/2:100:app.Fc_4.Value;
            app.masker.ap_filt = designfilt('bandpassiir', 'FilterOrder', 4, ...
               'HalfPowerFrequency1', app.masker.ap_carrier_list(1), 'HalfPowerFrequency2', app.masker.ap_carrier_list(end), ...
               'SampleRate', app.sampling_rate);
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            if value == "Normal"
                app.search_mode_normal();
            elseif value == "Fixed Carrier"
                app.search_mode_fixed_carrier();
            elseif value == "Fixed Modulation"
                app.search_mode_fixed_modulation();
            end
        end

        % Value changed function: Fc_check_1
        function Fc_check_1ValueChanged(app, event)
            value = app.Fc_check_1.Value;
            if value == 0
                app.Fc_1.Enable = 0;
            else
                app.Fc_1.Enable = 1;
            end
        end

        % Value changed function: Fc_check_2
        function Fc_check_2ValueChanged(app, event)
            value = app.Fc_check_2.Value;
            if value == 0
                app.Fc_2.Enable = 0;
            else
                app.Fc_2.Enable = 1;
            end
        end

        % Value changed function: Fc_check_3
        function Fc_check_3ValueChanged(app, event)
            value = app.Fc_check_3.Value;
            if value == 0
                app.Fc_3.Enable = 0;
            else
                app.Fc_3.Enable = 1;
            end
        end

        % Value changed function: Fc_check_4
        function Fc_check_4ValueChanged(app, event)
            value = app.Fc_check_4.Value;
            if value == 0
                app.Fc_4.Enable = 0;
            else
                app.Fc_4.Enable = 1;
            end
        end

        % Value changed function: Fc_check_5
        function Fc_check_5ValueChanged(app, event)
            value = app.Fc_check_5.Value;
            if value == 0
                app.Fc_5.Enable = 0;
            else
                app.Fc_5.Enable = 1;
            end
        end

        % Value changed function: Fm_check_1
        function Fm_check_1ValueChanged(app, event)
            value = app.Fm_check_1.Value;
            if value == 0
                app.Fm_1.Enable = 0;
            else
                app.Fm_1.Enable = 1;
            end
        end

        % Value changed function: Fm_check_2
        function Fm_check_2ValueChanged(app, event)
            value = app.Fm_check_2.Value;
            if value == 0
                app.Fm_2.Enable = 0;
            else
                app.Fm_2.Enable = 1;
            end
        end

        % Value changed function: Fm_check_3
        function Fm_check_3ValueChanged(app, event)
            value = app.Fm_check_3.Value;
            if value == 0
                app.Fm_3.Enable = 0;
            else
                app.Fm_3.Enable = 1;
            end
        end

        % Value changed function: Fm_check_4
        function Fm_check_4ValueChanged(app, event)
            value = app.Fm_check_4.Value;
            if value == 0
                app.Fm_4.Enable = 0;
            else
                app.Fm_4.Enable = 1;
            end
        end

        % Value changed function: Fm_check_5
        function Fm_check_5ValueChanged(app, event)
            value = app.Fm_check_5.Value;
            if value == 0
                app.Fm_5.Enable = 0;
            else
                app.Fm_5.Enable = 1;
            end
        end

        % Value changed function: ControlSwitch
        function ControlSwitchValueChanged(app, event)
            while app.ControlSwitch.Value == "On"
                tab = app.TabGroup.SelectedTab.Title;
                if tab == "Search"
                    fcs = app.search_get_carriers();
                    fms = app.search_get_modulators();
                    for i = 1: 1: size(fcs, 2)
                        for j = 1: 1: size(fms, 2)
                            if app.ControlSwitch.Value == "Off"
                                return;
                            end
                            [time, carrier, modulator, stimulus, stimulus_trigger, pulse_trigger] = app.build_biaural_search_stimulus(fcs(1, i), fms(1, j));
                            if app.debug == false
                                app.RPcontroller.ClearCOF();
                                if app.load_circuit("biaural_circuit.rcx")
                                    if app.transfer_search_parameters(stimulus, stimulus_trigger, pulse_trigger)
                                        app.RPcontroller.Run();
                                        app.RPcontroller.SoftTrg(1);
                                        app.RPcontroller.SoftTrg(2);
                                        app.search_plot_stimuli(time, carrier, modulator, stimulus, max(fcs), max(fms));
                                        pause(app.ISI.Value);
                                    else
                                        return;
                                    end
                                else
                                    return;
                                end
                            else
                                app.search_plot_stimuli(time, carrier, modulator, stimulus, max(fcs), max(fms));
                                player = audioplayer(stimulus, app.sampling_rate);
                                play(player);
                                pause(app.ISI.Value);
                            end
                        end
                    end
                elseif tab == "PRR"
                    fms = app.get_PRR_modulator();
                    max_fms.east = max(fms.east);
                    max_fms.west = max(fms.west);
                    if app.PRR_mode.Value == "Independent"
                        fcs = [app.East_PRR_Carrier.Value, app.West_PRR_Carrier.Value];
                    else
                        [fcs, ~] = app.get_nonIndependent_PRR_fcs_A();
                    end
                    max_fcs = max(fcs);
                    for j = 1: 1: size(fms.east, 2)
                        if app.ControlSwitch.Value == "Off"
                            return;
                        end
                        [times, carriers, modulators, stimuli, pulse_triggers, stimulus_triggers] = app.build_PRR_stimulus(fms.east(1, j), fms.west(1, j));
                        if app.debug == false
                            if app.RPcontroller.ClearCOF()
                                if app.load_circuit("biaural_circuit.rcx")
                                    if app.transfer_PRR_parameters(stimuli, stimulus_triggers, pulse_triggers)
                                        app.RPcontroller.Run();
                                        app.RPcontroller.SoftTrg(1);
                                        app.RPcontroller.SoftTrg(2);
                                        app.plot_PRR(times, carriers, modulators, stimuli, max_fcs, max_fms);
                                        pause(app.ISI.Value);
                                    else
                                        return;
                                    end
                                else
                                    return;
                                end
                            else
                                return;
                            end
                        else
                            app.plot_PRR(times, carriers, modulators, stimuli, max_fcs, max_fms);
                            max_nsamples = max([length(stimuli.east), length(stimuli.west)]);
                            stimulusleft = stimuli.east;
                            stimulusleft(:,end+1:max_nsamples) = 0;
                            stimulusright = stimuli.west;
                            stimulusright(:,end+1:max_nsamples) = 0;
                            stimulus = cat(1, stimulusleft, stimulusright);
                            player = audioplayer(stimulus, app.sampling_rate);
                            play(player);
                            pause(app.ISI.Value);
                        end
                    end
                elseif tab == "Natural"
                    if app.ControlSwitch.Value == "Off"
                        return;
                    end
                    [stimulus, stimulus_triggers, pulse_triggers, stimulus_time] = app.build_natural_call();
                    if app.debug == false
                        if app.RPcontroller.ClearCOF()
                            if app.load_circuit("biaural_circuit.rcx")
                                if app.transfer_PRR_parameters(stimulus, stimulus_triggers, pulse_triggers)
                                    app.RPcontroller.Run();
                                    app.RPcontroller.SoftTrg(1);
                                    app.RPcontroller.SoftTrg(2);
                                    app.plot_calls(stimulus, stimulus_time);
                                    if stimulus_time.west(1, end) > app.ISI.Value
                                        app.ISI.Value = stimulus_time.west(1, end)+1;
                                    end
                                    pause(app.ISI.Value);
                                else
                                    return;
                                end
                            else
                                return;
                            end
                        else
                            return;
                        end
                    else
                        app.plot_calls(stimulus, stimulus_time);
                        if stimulus_time.west(1, end) > app.ISI.Value
                            app.ISI.Value = stimulus_time.west(1, end)+1;
                        end
                        max_nsamples = max([length(stimulus.east), length(stimulus.west)]);
                        stimulusleft = stimulus.east;
                        stimulusleft(:,end+1:max_nsamples) = 0;
                        stimulusright = stimulus.west;
                        stimulusright(:,end+1:max_nsamples) = 0;
                        player_sound = cat(1, stimulusleft, stimulusright);
                        player = audioplayer(player_sound, app.sampling_rate);
                        play(player);
                        pause(app.ISI.Value);
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ControlSwitch.Value = "Off";
            delete(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
        end

        % Value changed function: Speaker
        function SpeakerValueChanged(app, event)

        end

        % Value changed function: PRR_mode
        function PRR_modeValueChanged(app, event)
            value = app.PRR_mode.Value;
            if value == "Select"
                app.Fm_PRR_check_1.Value = 1;
                app.Fm_PRR_1.Enable = 1;
                app.Fm_PRR_check_2.Value = 1;
                app.Fm_PRR_2.Enable = 1;
                app.Fm_PRR_check_2.Value = 1;
                app.Fm_PRR_2.Enable = 1;
                app.Fm_PRR_check_3.Value = 1;
                app.Fm_PRR_3.Enable = 1;
                app.Fm_PRR_check_4.Value = 1;
                app.Fm_PRR_4.Enable = 1;
                app.Fm_PRR_check_5.Value = 1;
                app.Fm_PRR_5.Enable = 1;
                app.Fm_PRR_start.Enable = 0;
                app.Fm_PRR_step.Enable = 0;
                app.Fm_PRR_end.Enable = 0;
                app.East_PRR_Pulse_rate.Enable = 0;
                app.West_PRR_Pulse_rate.Enable = 0;
                app.East_PRR_Gap_after_pulse.Enable = 0;
                app.East_PRR_Gap_time.Enable = 0;
                app.West_PRR_Gap_after_pulse.Enable = 0;
                app.West_PRR_Gap_time.Enable = 0;
            elseif value == "Cycle"
                app.Fm_PRR_check_1.Value = 0;
                app.Fm_PRR_1.Enable = 0;
                app.Fm_PRR_check_2.Value = 0;
                app.Fm_PRR_2.Enable = 0;
                app.Fm_PRR_check_2.Value = 0;
                app.Fm_PRR_2.Enable = 0;
                app.Fm_PRR_check_3.Value = 0;
                app.Fm_PRR_3.Enable = 0;
                app.Fm_PRR_check_4.Value = 0;
                app.Fm_PRR_4.Enable = 0;
                app.Fm_PRR_check_5.Value = 0;
                app.Fm_PRR_5.Enable = 0;
                app.Fm_PRR_start.Enable = 1;
                app.Fm_PRR_step.Enable = 1;
                app.Fm_PRR_end.Enable = 1;
                app.East_PRR_Pulse_rate.Enable = 0;
                app.West_PRR_Pulse_rate.Enable = 0;
                app.East_PRR_Gap_after_pulse.Enable = 0;
                app.East_PRR_Gap_time.Enable = 0;
                app.West_PRR_Gap_after_pulse.Enable = 0;
                app.West_PRR_Gap_time.Enable = 0;
            elseif value == "Independent"
                app.Fm_PRR_check_1.Value = 0;
                app.Fm_PRR_1.Enable = 0;
                app.Fm_PRR_check_2.Value = 0;
                app.Fm_PRR_2.Enable = 0;
                app.Fm_PRR_check_2.Value = 0;
                app.Fm_PRR_2.Enable = 0;
                app.Fm_PRR_check_3.Value = 0;
                app.Fm_PRR_3.Enable = 0;
                app.Fm_PRR_check_4.Value = 0;
                app.Fm_PRR_4.Enable = 0;
                app.Fm_PRR_check_5.Value = 0;
                app.Fm_PRR_5.Enable = 0;
                app.Fm_PRR_start.Enable = 0;
                app.Fm_PRR_step.Enable = 0;
                app.Fm_PRR_end.Enable = 0;
                app.East_PRR_Pulse_rate.Enable = 1;
                app.West_PRR_Pulse_rate.Enable = 1;
                app.East_PRR_Gap_after_pulse.Enable = 1;
                app.East_PRR_Gap_time.Enable = 1;
                app.West_PRR_Gap_after_pulse.Enable = 1;
                app.West_PRR_Gap_time.Enable = 1;
            end
        end

        % Value changed function: East_PRR_nPulses_check
        function East_PRR_nPulses_checkValueChanged(app, event)
            value = app.East_PRR_nPulses_check.Value;
            if value == 1
                app.East_PRR_nPulses.Enable = 1;
            else
                app.East_PRR_nPulses.Enable = 0;
            end
        end

        % Value changed function: West_PRR_nPulses_check
        function West_PRR_nPulses_checkValueChanged(app, event)
            value = app.West_PRR_nPulses_check.Value;
            if value == 1
                app.West_PRR_nPulses.Enable = 1;
            else
                app.West_PRR_nPulses.Enable = 0;
            end
        end

        % Value changed function: Fc_PRR_check_4
        function Fc_PRR_check_4ValueChanged(app, event)
            value = app.Fc_PRR_check_4.Value;
            app.Fc_PRR_4.Enable = value;
            app.Ec_PRR_4.Enable = value;
        end

        % Value changed function: Fc_PRR_check_3
        function Fc_PRR_check_3ValueChanged(app, event)
            value = app.Fc_PRR_check_3.Value;
            app.Fc_PRR_3.Enable = value;
            app.Ec_PRR_3.Enable = value;
        end

        % Value changed function: Fc_PRR_check_2
        function Fc_PRR_check_2ValueChanged(app, event)
            value = app.Fc_PRR_check_2.Value;
            app.Fc_PRR_2.Enable = value;
            app.Ec_PRR_2.Enable = value;
        end

        % Value changed function: Fc_PRR_check_1
        function Fc_PRR_check_1ValueChanged(app, event)
            value = app.Fc_PRR_check_1.Value;
            app.Fc_PRR_1.Enable = value;
            app.Ec_PRR_1.Enable = value;
        end

        % Value changed function: Fc_PRR_check_5
        function Fc_PRR_check_5ValueChanged(app, event)
            value = app.Fc_PRR_check_5.Value;
            app.Fc_PRR_5.Enable = value;
            app.Ec_PRR_5.Enable = value;
        end

        % Value changed function: Fm_PRR_check_1
        function Fm_PRR_check_1ValueChanged(app, event)
            app.Fm_PRR_1.Enable = app.Fm_PRR_check_1.Value;
        end

        % Value changed function: Fm_PRR_check_2
        function Fm_PRR_check_2ValueChanged(app, event)
            app.Fm_PRR_2.Enable = app.Fm_PRR_check_2.Value;
        end

        % Value changed function: Fm_PRR_check_3
        function Fm_PRR_check_3ValueChanged(app, event)
            app.Fm_PRR_3.Enable = app.Fm_PRR_check_3.Value; 
        end

        % Value changed function: Fm_PRR_check_4
        function Fm_PRR_check_4ValueChanged(app, event)
            app.Fm_PRR_4.Enable = app.Fm_PRR_check_4.Value;
        end

        % Value changed function: Fm_PRR_check_5
        function Fm_PRR_check_5ValueChanged(app, event)
            app.Fm_PRR_5.Enable = app.Fm_PRR_check_5.Value;
        end

        % Value changed function: PRREnvelope
        function PRREnvelopeValueChanged(app, event)
            value = app.PRREnvelope.Value;
            if value == "Rectangular"
                app.NaturalPulseDropDown.Enable = 0;
                app.West_PRR_nPulses.Value = 1;
                app.East_PRR_nPulses.Value = 1;
                app.West_PRR_PD_units.Value = "msec";
                app.West_PRR_PD.Value = 20;
                app.East_PRR_PD_units.Value = "msec";
                app.East_PRR_PD.Value = 20;
                app.West_PRR_RT_units.Enable = 1;
                app.West_PRR_RT.Enable = 1;
                app.East_PRR_RT_units.Enable = 1;
                app.East_PRR_RT.Enable=1;
                app.West_PRR_RT_units.Value = "msec";
                app.West_PRR_RT.Value = 0;
                app.East_PRR_RT_units.Value = "msec";
                app.East_PRR_RT.Value = 0;
                app.PRR_mode.Value = "Independent";
                app.PRR_modeValueChanged();
                app.PRR_mode.Enable = 0;
                app.West_PRR_Pulse_rate.Value = 1;
                app.East_PRR_Pulse_rate.Value = 1;
                app.West_PRR_Gap_after_pulse.Enable = 0;
                app.East_PRR_Gap_after_pulse.Enable = 0;
                app.West_PRR_Gap_time.Enable = 0;
                app.East_PRR_Gap_time.Enable = 0;
            elseif value == "Triangular"
                app.NaturalPulseDropDown.Enable = 0;
                app.West_PRR_nPulses.Value = 5;
                app.East_PRR_nPulses.Value = 5;
                app.West_PRR_PD_units.Enable = 1;
                app.West_PRR_PD_units.Value = "msec";
                app.West_PRR_PD.Enable = 1;
                app.West_PRR_PD.Value = 15;
                app.East_PRR_PD_units.Enable = 1;
                app.East_PRR_PD_units.Value = "msec";
                app.East_PRR_PD.Enable = 1;
                app.East_PRR_PD.Value = 15;
                app.West_PRR_RT_units.Enable = 1;
                app.West_PRR_RT_units.Value = "%";
                app.West_PRR_RT.Enable = 1;
                app.West_PRR_RT.Value = 80;
                app.East_PRR_RT_units.Enable = 1;
                app.East_PRR_RT_units.Value = "%";
                app.East_PRR_RT.Enable=1;
                app.East_PRR_RT.Value = 20;
                app.PRR_mode.Value = "Select";
                app.PRR_modeValueChanged();
                app.PRR_mode.Enable = 1;
                app.West_PRR_Pulse_rate.Value = 10;
                app.East_PRR_Pulse_rate.Value = 10;
            elseif value == "Exponential"
                app.NaturalPulseDropDown.Enable = 0;
                app.West_PRR_nPulses.Value = 5;
                app.East_PRR_nPulses.Value = 5;
                app.West_PRR_PD_units.Enable = 1;
                app.West_PRR_PD_units.Value = "msec";
                app.West_PRR_PD.Enable = 1;
                app.West_PRR_PD.Value = 15;
                app.East_PRR_PD_units.Enable = 1;
                app.East_PRR_PD_units.Value = "msec";
                app.East_PRR_PD.Enable = 1;
                app.East_PRR_PD.Value = 15;
                app.West_PRR_RT_units.Enable = 0;
                app.West_PRR_RT_units.Value = "msec";
                app.West_PRR_RT.Enable = 0;
                app.West_PRR_RT.Value = app.West_PRR_PD.Value/10;
                app.East_PRR_RT_units.Enable = 0;
                app.East_PRR_RT_units.Value = "msec";
                app.East_PRR_RT.Enable=0;
                app.East_PRR_RT.Value = app.East_PRR_PD.Value/10;
                app.PRR_mode.Value = "Select";
                app.PRR_modeValueChanged();
                app.PRR_mode.Enable = 1;
                app.West_PRR_Pulse_rate.Value = 10;
                app.East_PRR_Pulse_rate.Value = 10;
            elseif value == "Natural"
                app.NaturalPulseDropDown.Enable = 1;
                app.West_PRR_nPulses.Value = 5;
                app.East_PRR_nPulses.Value = 5;
                app.West_PRR_PD_units.Enable = 0;
                app.West_PRR_PD.Enable = 0;
                app.East_PRR_PD_units.Enable = 0;
                app.East_PRR_PD.Enable = 0;
                app.West_PRR_RT_units.Enable = 0;
                app.West_PRR_RT.Enable = 0;
                app.East_PRR_RT_units.Enable = 0;
                app.East_PRR_RT.Enable = 0;
                app.PRR_mode.Value = "Select";
                app.PRR_modeValueChanged();
                app.PRR_mode.Enable = 1;
                app.West_PRR_Pulse_rate.Value = 10;
                app.East_PRR_Pulse_rate.Value = 10;
                app.NaturalPulseDropDownValueChanged();
            end
        end

        % Value changed function: West_PRR_Pulse_rate
        function West_PRR_Pulse_rateValueChanged(app, event)
            app.West_PRR_Gap_time.Value = (1/app.West_PRR_Pulse_rate.Value)*1e3;
        end

        % Value changed function: East_PRR_Pulse_rate
        function East_PRR_Pulse_rateValueChanged(app, event)
            app.East_PRR_Gap_time.Value = (1/app.East_PRR_Pulse_rate.Value)*1e3;
            
        end

        % Value changed function: NaturalPulseDropDown
        function NaturalPulseDropDownValueChanged(app, event)
            species = app.SpeciesDropDown.Value;
            if species == "Rana pipiens"
                call_pulses_index = find(strcmp(app.call_pulses_list.Rpipiens, app.NaturalPulseDropDown.Value));
                on_pulse = app.call_pulses.Rpipiens(call_pulses_index, 1);
            elseif species == "Hyla versicolor"
                call_pulses_index = find(strcmp(app.call_pulses_list.Hversicolor, app.NaturalPulseDropDown.Value));
                on_pulse = app.call_pulses.Hversicolor(call_pulses_index, 1);
            elseif species == "Hyla chrysoscelis"
                call_pulses_index = find(strcmp(app.call_pulses_list.Hchrysoscelis, app.NaturalPulseDropDown.Value));
                on_pulse = app.call_pulses.Hchrysoscelis(call_pulses_index, 1);
            elseif species == "Pseudacris feriarum"
                call_pulses_index = find(strcmp(app.call_pulses_list.Pferiarum, app.NaturalPulseDropDown.Value));
                on_pulse = app.call_pulses.Pferiarum(call_pulses_index, 1);
            end
            on_pulse = cell2mat(on_pulse);
            pulse_on_duration = (size(on_pulse, 2)/app.sampling_rate)*1e3;
            app.West_PRR_PD.Value = pulse_on_duration;
            app.West_PRR_PD_units.Value = "msec";
            app.East_PRR_PD.Value = pulse_on_duration;
            app.East_PRR_PD_units.Value = "msec";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SearchTab = uitab(app.TabGroup);
            app.SearchTab.Title = 'Search';
            app.ControlPanel = uipanel(app.SearchTab);
            app.ControlPanel.Title = 'Control';
            app.CarrierFrequenciesHzPanel = uipanel(app.ControlPanel);
            app.CarrierFrequenciesHzPanel.Title = 'Carrier Frequencies (Hz)';
            app.Fc_check_1 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_1.ValueChangedFcn = createCallbackFcn(app, @Fc_check_1ValueChanged, true);
            app.Fc_check_1.Text = '';
            app.Fc_check_1.Value = true;
            app.Fc_1 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_1.Value = 150;
            app.Fc_2 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_2.Value = 300;
            app.Fc_check_2 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_2.ValueChangedFcn = createCallbackFcn(app, @Fc_check_2ValueChanged, true);
            app.Fc_check_2.Text = '';
            app.Fc_check_2.Value = true;
            app.Fc_3 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_3.Value = 600;
            app.Fc_check_3 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_3.ValueChangedFcn = createCallbackFcn(app, @Fc_check_3ValueChanged, true);
            app.Fc_check_3.Text = '';
            app.Fc_check_3.Value = true;
            app.Fc_4 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_4.Value = 1100;
            app.Fc_check_4 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_4.ValueChangedFcn = createCallbackFcn(app, @Fc_check_4ValueChanged, true);
            app.Fc_check_4.Text = '';
            app.Fc_check_4.Value = true;
            app.Fc_5 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_5.Value = 1600;
            app.Fc_check_5 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_5.ValueChangedFcn = createCallbackFcn(app, @Fc_check_5ValueChanged, true);
            app.Fc_check_5.Text = '';
            app.Fc_check_5.Value = true;
            app.ModulatorFrequenciesHzPanel = uipanel(app.ControlPanel);
            app.ModulatorFrequenciesHzPanel.Title = 'Modulator Frequencies (Hz)';
            app.Fm_check_1 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_1.ValueChangedFcn = createCallbackFcn(app, @Fm_check_1ValueChanged, true);
            app.Fm_check_1.Text = '';
            app.Fm_check_1.Value = true;
            app.Fm_1 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_1.Value = 10;
            app.Fm_2 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_2.Value = 20;
            app.Fm_check_2 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_2.ValueChangedFcn = createCallbackFcn(app, @Fm_check_2ValueChanged, true);
            app.Fm_check_2.Text = '';
            app.Fm_check_2.Value = true;
            app.Fm_3 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_3.Value = 40;
            app.Fm_check_3 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_3.ValueChangedFcn = createCallbackFcn(app, @Fm_check_3ValueChanged, true);
            app.Fm_check_3.Text = '';
            app.Fm_check_3.Value = true;
            app.Fm_4 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_4.Value = 60;
            app.Fm_check_4 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_4.ValueChangedFcn = createCallbackFcn(app, @Fm_check_4ValueChanged, true);
            app.Fm_check_4.Text = '';
            app.Fm_check_4.Value = true;
            app.Fm_5 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_5.Value = 80;
            app.Fm_check_5 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_5.ValueChangedFcn = createCallbackFcn(app, @Fm_check_5ValueChanged, true);
            app.Fm_check_5.Text = '';
            app.Fm_check_5.Value = true;
            app.ModeDropDownLabel = uilabel(app.ControlPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.ControlPanel);
            app.ModeDropDown.Items = {'Normal', 'Fixed Carrier', 'Fixed Modulation'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Normal';
            app.FrequencyCyclerHzPanel = uipanel(app.ControlPanel);
            app.FrequencyCyclerHzPanel.Title = 'Frequency Cycler (Hz)';
            app.StartEditFieldLabel = uilabel(app.FrequencyCyclerHzPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.Start_frequency = uieditfield(app.FrequencyCyclerHzPanel, 'numeric');
            app.Start_frequency.Value = 300;
            app.StepEditFieldLabel = uilabel(app.FrequencyCyclerHzPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.Step_frequency = uieditfield(app.FrequencyCyclerHzPanel, 'numeric');
            app.Step_frequency.Value = 20;
            app.EndEditFieldLabel = uilabel(app.FrequencyCyclerHzPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.End_frequency = uieditfield(app.FrequencyCyclerHzPanel, 'numeric');
            app.End_frequency.Value = 600;
            app.StimulusDurationmsecLabel = uilabel(app.SearchTab);
            app.StimulusDurationmsecLabel.Text = 'Stimulus Duration (msec)';
            app.Search_stimulus_duration = uispinner(app.SearchTab);
            app.Search_stimulus_duration.Step = 50;
            app.Search_stimulus_duration.Value = 400;
            app.PRRTab = uitab(app.TabGroup);
            app.PRRTab.Title = 'PRR';
            app.ControlPanel_PRR = uipanel(app.PRRTab);
            app.ControlPanel_PRR.Title = 'Control';
            app.CarrierFrequenciesHzPanel_2 = uipanel(app.ControlPanel_PRR);
            app.CarrierFrequenciesHzPanel_2.Title = 'Carrier Frequencies (Hz)';
            app.Fc_PRR_check_1 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_1.ValueChangedFcn = createCallbackFcn(app, @Fc_PRR_check_1ValueChanged, true);
            app.Fc_PRR_check_1.Text = '';
            app.Fc_PRR_1 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_1.Enable = 'off';
            app.Fc_PRR_1.Value = 150;
            app.Fc_PRR_2 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_2.Enable = 'off';
            app.Fc_PRR_2.Value = 300;
            app.Fc_PRR_check_2 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_2.ValueChangedFcn = createCallbackFcn(app, @Fc_PRR_check_2ValueChanged, true);
            app.Fc_PRR_check_2.Text = '';
            app.Fc_PRR_3 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_3.Enable = 'off';
            app.Fc_PRR_3.Value = 600;
            app.Fc_PRR_check_3 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_3.ValueChangedFcn = createCallbackFcn(app, @Fc_PRR_check_3ValueChanged, true);
            app.Fc_PRR_check_3.Text = '';
            app.Fc_PRR_4 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_4.Enable = 'off';
            app.Fc_PRR_4.Value = 1100;
            app.Fc_PRR_check_4 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_4.ValueChangedFcn = createCallbackFcn(app, @Fc_PRR_check_4ValueChanged, true);
            app.Fc_PRR_check_4.Text = '';
            app.Fc_PRR_5 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_5.Value = 1600;
            app.Fc_PRR_check_5 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_5.ValueChangedFcn = createCallbackFcn(app, @Fc_PRR_check_5ValueChanged, true);
            app.Fc_PRR_check_5.Text = '';
            app.Fc_PRR_check_5.Value = true;
            app.EnergyPanel = uipanel(app.ControlPanel_PRR);
            app.EnergyPanel.Title = 'Energy';
            app.Ec_PRR_1 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_1.Enable = 'off';
            app.Ec_PRR_2 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_2.Enable = 'off';
            app.Ec_PRR_3 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_3.Enable = 'off';
            app.Ec_PRR_4 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_4.Enable = 'off';
            app.Ec_PRR_5 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_5.Value = 1;
            app.PRRppsSelPanel = uipanel(app.ControlPanel_PRR);
            app.PRRppsSelPanel.Title = 'PRR (pps): Sel';
            app.Fm_PRR_check_1 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_1.ValueChangedFcn = createCallbackFcn(app, @Fm_PRR_check_1ValueChanged, true);
            app.Fm_PRR_check_1.Text = '';
            app.Fm_PRR_check_1.Value = true;
            app.Fm_PRR_1 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_1.Value = 5;
            app.Fm_PRR_2 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_2.Value = 10;
            app.Fm_PRR_check_2 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_2.ValueChangedFcn = createCallbackFcn(app, @Fm_PRR_check_2ValueChanged, true);
            app.Fm_PRR_check_2.Text = '';
            app.Fm_PRR_check_2.Value = true;
            app.Fm_PRR_3 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_3.Value = 20;
            app.Fm_PRR_check_3 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_3.ValueChangedFcn = createCallbackFcn(app, @Fm_PRR_check_3ValueChanged, true);
            app.Fm_PRR_check_3.Text = '';
            app.Fm_PRR_check_3.Value = true;
            app.Fm_PRR_4 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_4.Value = 40;
            app.Fm_PRR_check_4 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_4.ValueChangedFcn = createCallbackFcn(app, @Fm_PRR_check_4ValueChanged, true);
            app.Fm_PRR_check_4.Text = '';
            app.Fm_PRR_check_4.Value = true;
            app.Fm_PRR_5 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_5.Value = 60;
            app.Fm_PRR_check_5 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_5.ValueChangedFcn = createCallbackFcn(app, @Fm_PRR_check_5ValueChanged, true);
            app.Fm_PRR_check_5.Text = '';
            app.Fm_PRR_check_5.Value = true;
            app.PRRModeDropDownLabel = uilabel(app.ControlPanel_PRR);
            app.PRRModeDropDownLabel.Text = 'PRR Mode';
            app.PRR_mode = uidropdown(app.ControlPanel_PRR);
            app.PRR_mode.Items = {'Select', 'Cycle', 'Independent'};
            app.PRR_mode.ValueChangedFcn = createCallbackFcn(app, @PRR_modeValueChanged, true);
            app.PRR_mode.Value = 'Select';
            app.PRRCyclerppsCyclePanel = uipanel(app.ControlPanel_PRR);
            app.PRRCyclerppsCyclePanel.Title = 'PRR Cycler (pps): Cycle';
            app.StartEditFieldLabel_2 = uilabel(app.PRRCyclerppsCyclePanel);
            app.StartEditFieldLabel_2.Enable = 'off';
            app.StartEditFieldLabel_2.Text = 'Start';
            app.Fm_PRR_start = uieditfield(app.PRRCyclerppsCyclePanel, 'numeric');
            app.Fm_PRR_start.Enable = 'off';
            app.Fm_PRR_start.Value = 10;
            app.StepEditFieldLabel_2 = uilabel(app.PRRCyclerppsCyclePanel);
            app.StepEditFieldLabel_2.Enable = 'off';
            app.StepEditFieldLabel_2.Text = 'Step';
            app.Fm_PRR_step = uieditfield(app.PRRCyclerppsCyclePanel, 'numeric');
            app.Fm_PRR_step.Enable = 'off';
            app.Fm_PRR_step.Value = 10;
            app.EndEditFieldLabel_2 = uilabel(app.PRRCyclerppsCyclePanel);
            app.EndEditFieldLabel_2.Enable = 'off';
            app.EndEditFieldLabel_2.Text = 'End';
            app.Fm_PRR_end = uieditfield(app.PRRCyclerppsCyclePanel, 'numeric');
            app.Fm_PRR_end.Enable = 'off';
            app.Fm_PRR_end.Value = 60;
            app.EnvelopeDropDownLabel = uilabel(app.ControlPanel_PRR);
            app.EnvelopeDropDownLabel.Text = 'Envelope';
            app.PRREnvelope = uidropdown(app.ControlPanel_PRR);
            app.PRREnvelope.Items = {'Triangular', 'Exponential', 'Rectangular', 'Natural'};
            app.PRREnvelope.ValueChangedFcn = createCallbackFcn(app, @PRREnvelopeValueChanged, true);
            app.PRREnvelope.Value = 'Triangular';
            app.NaturalPulseDropDownLabel = uilabel(app.ControlPanel_PRR);
            app.NaturalPulseDropDownLabel.Text = 'Natural Pulse';
            app.NaturalPulseDropDown = uidropdown(app.ControlPanel_PRR);
            app.NaturalPulseDropDown.Items = {};
            app.NaturalPulseDropDown.ValueChangedFcn = createCallbackFcn(app, @NaturalPulseDropDownValueChanged, true);
            app.NaturalPulseDropDown.Value = {};
            app.EastPanel = uipanel(app.PRRTab);
            app.EastPanel.Title = 'East';
            app.nPulsesLabel = uilabel(app.EastPanel);
            app.nPulsesLabel.Text = 'nPulses';
            app.East_PRR_nPulses = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_nPulses.Value = 5;
            app.PDLabel = uilabel(app.EastPanel);
            app.PDLabel.Text = 'PD';
            app.East_PRR_PD = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_PD.Value = 15;
            app.East_PRR_PD_units = uidropdown(app.EastPanel);
            app.East_PRR_PD_units.Items = {'msec', '%'};
            app.East_PRR_PD_units.Value = 'msec';
            app.East_PRR_RT_units = uidropdown(app.EastPanel);
            app.East_PRR_RT_units.Items = {'msec', '%'};
            app.East_PRR_RT_units.Value = '%';
            app.RTLabel = uilabel(app.EastPanel);
            app.RTLabel.Text = 'RT';
            app.East_PRR_RT = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_RT.Value = 20;
            app.PulserateppsLabel = uilabel(app.EastPanel);
            app.PulserateppsLabel.Text = 'Pulse rate (pps)';
            app.East_PRR_Pulse_rate = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_Pulse_rate.ValueChangedFcn = createCallbackFcn(app, @East_PRR_Pulse_rateValueChanged, true);
            app.East_PRR_Pulse_rate.Enable = 'off';
            app.East_PRR_Pulse_rate.Value = 10;
            app.GapafterpulseLabel = uilabel(app.EastPanel);
            app.GapafterpulseLabel.Text = 'Gap after pulse';
            app.East_PRR_Gap_after_pulse = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_Gap_after_pulse.Enable = 'off';
            app.GaptimemsecLabel = uilabel(app.EastPanel);
            app.GaptimemsecLabel.Text = 'Gap time (msec)';
            app.East_PRR_Gap_time = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_Gap_time.Enable = 'off';
            app.East_PRR_Gap_time.Value = 10;
            app.East_PRR_nPulses_check = uicheckbox(app.EastPanel);
            app.East_PRR_nPulses_check.ValueChangedFcn = createCallbackFcn(app, @East_PRR_nPulses_checkValueChanged, true);
            app.East_PRR_nPulses_check.Text = '';
            app.East_PRR_nPulses_check.Value = true;
            app.MaskerListBoxLabel = uilabel(app.EastPanel);
            app.MaskerListBoxLabel.Text = 'Masker';
            app.EastMasker = uilistbox(app.EastPanel);
            app.EastMasker.Items = {'None', 'BP Info', 'BP Energy', 'BP Cont', 'AP Info', 'AP Energy', 'AP Cont', 'BP Carrier', 'White Noise'};
            app.EastMasker.Value = 'None';
            app.East_PRR_multiGap_check = uicheckbox(app.EastPanel);
            app.East_PRR_multiGap_check.Text = 'Multi-Gap';
            app.CarrierHzEditFieldLabel = uilabel(app.EastPanel);
            app.CarrierHzEditFieldLabel.Text = 'Carrier (Hz)';
            app.East_PRR_Carrier = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_Carrier.Limits = [0 Inf];
            app.East_PRR_Carrier.Value = 1600;
            app.WestPanel = uipanel(app.PRRTab);
            app.WestPanel.Title = 'West';
            app.nPulsesLabel_2 = uilabel(app.WestPanel);
            app.nPulsesLabel_2.Text = 'nPulses';
            app.West_PRR_nPulses = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_nPulses.Value = 5;
            app.PDLabel_2 = uilabel(app.WestPanel);
            app.PDLabel_2.Text = 'PD';
            app.West_PRR_PD = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_PD.Value = 15;
            app.West_PRR_PD_units = uidropdown(app.WestPanel);
            app.West_PRR_PD_units.Items = {'msec', '%'};
            app.West_PRR_PD_units.Value = 'msec';
            app.West_PRR_RT_units = uidropdown(app.WestPanel);
            app.West_PRR_RT_units.Items = {'msec', '%'};
            app.West_PRR_RT_units.Value = '%';
            app.RTLabel_2 = uilabel(app.WestPanel);
            app.RTLabel_2.Text = 'RT';
            app.West_PRR_RT = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_RT.Value = 80;
            app.PulserateppsLabel_2 = uilabel(app.WestPanel);
            app.PulserateppsLabel_2.Text = 'Pulse rate (pps)';
            app.West_PRR_Pulse_rate = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_Pulse_rate.ValueChangedFcn = createCallbackFcn(app, @West_PRR_Pulse_rateValueChanged, true);
            app.West_PRR_Pulse_rate.Enable = 'off';
            app.West_PRR_Pulse_rate.Value = 10;
            app.GapafterpulseLabel_2 = uilabel(app.WestPanel);
            app.GapafterpulseLabel_2.Text = 'Gap after pulse';
            app.West_PRR_Gap_after_pulse = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_Gap_after_pulse.Enable = 'off';
            app.GaptimemsecLabel_2 = uilabel(app.WestPanel);
            app.GaptimemsecLabel_2.Text = 'Gap time (msec)';
            app.West_PRR_Gap_time = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_Gap_time.Enable = 'off';
            app.West_PRR_Gap_time.Value = 10;
            app.West_PRR_nPulses_check = uicheckbox(app.WestPanel);
            app.West_PRR_nPulses_check.ValueChangedFcn = createCallbackFcn(app, @West_PRR_nPulses_checkValueChanged, true);
            app.West_PRR_nPulses_check.Text = '';
            app.West_PRR_nPulses_check.Value = true;
            app.MaskerListBox_2Label = uilabel(app.WestPanel);
            app.MaskerListBox_2Label.Text = 'Masker';
            app.WestMasker = uilistbox(app.WestPanel);
            app.WestMasker.Items = {'None', 'BP Info', 'BP Energy', 'BP Cont', 'AP Info', 'AP Energy', 'AP Cont', 'BP Carrier', 'White Noise'};
            app.WestMasker.Value = 'None';
            app.West_PRR_multiGap_check = uicheckbox(app.WestPanel);
            app.West_PRR_multiGap_check.Text = 'Multi-Gap';
            app.CarrierHzEditField_2Label = uilabel(app.WestPanel);
            app.CarrierHzEditField_2Label.Text = 'Carrier (Hz)';
            app.West_PRR_Carrier = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_Carrier.Limits = [0 Inf];
            app.West_PRR_Carrier.Value = 1600;
            app.NaturalTab = uitab(app.TabGroup);
            app.NaturalTab.Title = 'Natural';
            app.ControlPanel_Natural = uipanel(app.NaturalTab);
            app.ControlPanel_Natural.Title = 'Control';
            app.PlayModeLabel = uilabel(app.ControlPanel_Natural);
            app.PlayModeLabel.Text = 'Play Mode';
            app.NaturalPlayMode = uidropdown(app.ControlPanel_Natural);
            app.NaturalPlayMode.Items = {'Normal', 'Reversed'};
            app.NaturalPlayMode.Value = 'Normal';
            app.CallDropDownLabel = uilabel(app.ControlPanel_Natural);
            app.CallDropDownLabel.Text = 'Call';
            app.NaturalCall = uidropdown(app.ControlPanel_Natural);
            app.NaturalCall.Items = {};
            app.NaturalCall.Value = {};
            app.CarrierDropDownLabel = uilabel(app.ControlPanel_Natural);
            app.CarrierDropDownLabel.Text = 'Carrier';
            app.NaturalCarrier = uidropdown(app.ControlPanel_Natural);
            app.NaturalCarrier.Items = {'Natural', 'Tone', 'White Noise'};
            app.NaturalCarrier.Value = 'Natural';
            app.ToneHzEditFieldLabel = uilabel(app.ControlPanel_Natural);
            app.ToneHzEditFieldLabel.Text = 'Tone Hz';
            app.NaturalToneHz = uieditfield(app.ControlPanel_Natural, 'numeric');
            app.NaturalToneHz.Limits = [0 Inf];
            app.NaturalToneHz.Value = 1600;
            app.Speaker = uiknob(app.UIFigure, 'discrete');
            app.Speaker.Items = {'East', 'Biaural', 'West'};
            app.Speaker.ValueChangedFcn = createCallbackFcn(app, @SpeakerValueChanged, true);
            app.Speaker.Value = 'West';
            app.ControlSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValueChanged, true);
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.ConnectionLampLabel = uilabel(app.StatusPanel);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.StatusPanel);
            app.LoadCircuitLampLabel = uilabel(app.StatusPanel);
            app.LoadCircuitLampLabel.Text = 'Load Circuit';
            app.LoadCircuitLamp = uilamp(app.StatusPanel);
            app.LoadParametersLampLabel = uilabel(app.StatusPanel);
            app.LoadParametersLampLabel.Text = 'Load Parameters';
            app.LoadParametersLamp = uilamp(app.StatusPanel);
            app.WestSpeakerPanel = uipanel(app.UIFigure);
            app.WestSpeakerPanel.Title = 'West Speaker';
            app.West_modulator_plot = uiaxes(app.WestSpeakerPanel);
            title(app.West_modulator_plot, 'Modulator')
            xlabel(app.West_modulator_plot, 'time (sec)')
            ylabel(app.West_modulator_plot, 'Amplitude (V)')
            zlabel(app.West_modulator_plot, 'Z')
            app.West_carrier_plot = uiaxes(app.WestSpeakerPanel);
            title(app.West_carrier_plot, 'Carrier')
            xlabel(app.West_carrier_plot, 'time (sec)')
            ylabel(app.West_carrier_plot, 'Amplitude (V)')
            zlabel(app.West_carrier_plot, 'Z')
            app.West_stimulus_plot = uiaxes(app.WestSpeakerPanel);
            title(app.West_stimulus_plot, 'Stimulus')
            xlabel(app.West_stimulus_plot, 'time (sec)')
            ylabel(app.West_stimulus_plot, 'Amplitude (V)')
            zlabel(app.West_stimulus_plot, 'Z')
            app.West_plot_type = uiswitch(app.WestSpeakerPanel, 'rocker');
            app.West_plot_type.Items = {'Time', 'FFT'};
            app.West_plot_type.Value = 'Time';
            app.West_current_carrier = uieditfield(app.WestSpeakerPanel, 'numeric');
            app.West_current_modulator = uieditfield(app.WestSpeakerPanel, 'numeric');
            app.EastSpeakerPanel = uipanel(app.UIFigure);
            app.EastSpeakerPanel.Title = 'East Speaker';
            app.East_modulator_plot = uiaxes(app.EastSpeakerPanel);
            title(app.East_modulator_plot, 'Modulator')
            xlabel(app.East_modulator_plot, 'time (sec)')
            ylabel(app.East_modulator_plot, 'Amplitude (V)')
            zlabel(app.East_modulator_plot, 'Z')
            app.East_carrier_plot = uiaxes(app.EastSpeakerPanel);
            title(app.East_carrier_plot, 'Carrier')
            xlabel(app.East_carrier_plot, 'time (sec)')
            ylabel(app.East_carrier_plot, 'Amplitude (V)')
            zlabel(app.East_carrier_plot, 'Z')
            app.East_stimulus_plot = uiaxes(app.EastSpeakerPanel);
            title(app.East_stimulus_plot, 'Stimulus')
            xlabel(app.East_stimulus_plot, 'time (sec)')
            ylabel(app.East_stimulus_plot, 'Amplitude (V)')
            zlabel(app.East_stimulus_plot, 'Z')
            app.East_plot_type = uiswitch(app.EastSpeakerPanel, 'rocker');
            app.East_plot_type.Items = {'Time', 'FFT'};
            app.East_plot_type.Value = 'Time';
            app.East_current_carrier = uieditfield(app.EastSpeakerPanel, 'numeric');
            app.East_current_modulator = uieditfield(app.EastSpeakerPanel, 'numeric');
            app.InterStimulusIntervalsecLabel = uilabel(app.UIFigure);
            app.InterStimulusIntervalsecLabel.Text = 'Inter-Stimulus Interval (sec)';
            app.ISI = uispinner(app.UIFigure);
            app.ISI.Step = 0.5;
            app.ISI.Value = 2;
            app.InterSpeakerIntervalmsecLabel = uilabel(app.UIFigure);
            app.InterSpeakerIntervalmsecLabel.Text = 'Inter-Speaker Interval (msec)';
            app.Inter_speaker_interval = uispinner(app.UIFigure);
            app.Inter_speaker_interval.Step = 5;
            app.SpeciesDropDownLabel = uilabel(app.UIFigure);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.UIFigure);
            app.SpeciesDropDown.Items = {'Rana pipiens', 'Hyla versicolor', 'Hyla chrysoscelis', 'Pseudacris feriarum'};
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciesDropDownValueChanged, true);
            app.SpeciesDropDown.Value = 'Rana pipiens';
            app.CombineOutputCheckBox = uicheckbox(app.UIFigure);
            app.CombineOutputCheckBox.Text = 'Combine Output';
            app.EastAttenuatorSliderLabel = uilabel(app.UIFigure);
            app.EastAttenuatorSliderLabel.Text = 'East Attenuator';
            app.EastAttenuatorSlider = uislider(app.UIFigure);
            app.EastAttenuatorSlider.Limits = [-72 0];
            app.EastAttenuatorSlider.MajorTicks = [-72 -69 -66 -63 -60 -57 -54 -51 -48 -45 -42 -39 -36 -33 -30 -27 -24 -21 -18 -15 -12 -9 -6 -3 0];
            app.EastAttenuatorSlider.MajorTickLabels = {'-72', '-69', '-66', '-63', '-60', '-57', '-54', '-51', '-48', '-45', '-42', '-39', '-36', '-33', '-30', '-27', '-24', '-21', '-18', '-15', '-12', '-9', '-6', '-3', '0'};
            app.EastAttenuatorSlider.MinorTicks = [-72 -71.5 -71 -70.5 -70 -69.5 -69 -68.5 -68 -67.5 -67 -66.5 -66 -65.5 -65 -64.5 -64 -63.5 -63 -62.5 -62 -61.5 -61 -60.5 -60 -59.5 -59 -58.5 -58 -57.5 -57 -56.5 -56 -55.5 -55 -54.5 -54 -53.5 -53 -52.5 -52 -51.5 -51 -50.5 -50 -49.5 -49 -48.5 -48 -47.5 -47 -46.5 -46 -45.5 -45 -44.5 -44 -43.5 -43 -42.5 -42 -41.5 -41 -40.5 -40 -39.5 -39 -38.5 -38 -37.5 -37 -36.5 -36 -35.5 -35 -34.5 -34 -33.5 -33 -32.5 -32 -31.5 -31 -30.5 -30 -29.5 -29 -28.5 -28 -27.5 -27 -26.5 -26 -25.5 -25 -24.5 -24 -23.5 -23 -22.5 -22 -21.5 -21 -20.5 -20 -19.5 -19 -18.5 -18 -17.5 -17 -16.5 -16 -15.5 -15 -14.5 -14 -13.5 -13 -12.5 -12 -11.5 -11 -10.5 -10 -9.5 -9 -8.5 -8 -7.5 -7 -6.5 -6 -5.5 -5 -4.5 -4 -3.5 -3 -2.5 -2 -1.5 -1 -0.5 0];
            app.EastAttenuatorSlider.Value = -27;
            app.WestAttenuatorSliderLabel = uilabel(app.UIFigure);
            app.WestAttenuatorSliderLabel.Text = 'West Attenuator';
            app.WestAttenuatorSlider = uislider(app.UIFigure);
            app.WestAttenuatorSlider.Limits = [-72 0];
            app.WestAttenuatorSlider.MajorTicks = [-72 -69 -66 -63 -60 -57 -54 -51 -48 -45 -42 -39 -36 -33 -30 -27 -24 -21 -18 -15 -12 -9 -6 -3 0];
            app.WestAttenuatorSlider.MajorTickLabels = {'-72', '-69', '-66', '-63', '-60', '-57', '-54', '-51', '-48', '-45', '-42', '-39', '-36', '-33', '-30', '-27', '-24', '-21', '-18', '-15', '-12', '-9', '-6', '-3', '0'};
            app.WestAttenuatorSlider.MinorTicks = [-72 -71.5 -71 -70.5 -70 -69.5 -69 -68.5 -68 -67.5 -67 -66.5 -66 -65.5 -65 -64.5 -64 -63.5 -63 -62.5 -62 -61.5 -61 -60.5 -60 -59.5 -59 -58.5 -58 -57.5 -57 -56.5 -56 -55.5 -55 -54.5 -54 -53.5 -53 -52.5 -52 -51.5 -51 -50.5 -50 -49.5 -49 -48.5 -48 -47.5 -47 -46.5 -46 -45.5 -45 -44.5 -44 -43.5 -43 -42.5 -42 -41.5 -41 -40.5 -40 -39.5 -39 -38.5 -38 -37.5 -37 -36.5 -36 -35.5 -35 -34.5 -34 -33.5 -33 -32.5 -32 -31.5 -31 -30.5 -30 -29.5 -29 -28.5 -28 -27.5 -27 -26.5 -26 -25.5 -25 -24.5 -24 -23.5 -23 -22.5 -22 -21.5 -21 -20.5 -20 -19.5 -19 -18.5 -18 -17.5 -17 -16.5 -16 -15.5 -15 -14.5 -14 -13.5 -13 -12.5 -12 -11.5 -11 -10.5 -10 -9.5 -9 -8.5 -8 -7.5 -7 -6.5 -6 -5.5 -5 -4.5 -4 -3.5 -3 -2.5 -2 -1.5 -1 -0.5 0];
            app.WestAttenuatorSlider.Value = -27;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimulus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end